classdef TsetlinGUI_With_Call_Model_2_Selectfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SensitivityStep                 matlab.ui.control.EditField
        ThresholdStep                   matlab.ui.control.EditField
        SelectTestFileButton            matlab.ui.control.Button
        SelectedFileLabel               matlab.ui.control.Button
        TrainingLamp                    matlab.ui.control.Lamp
        TrainingLampLabel               matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        Switch2                         matlab.ui.control.Switch
        ControlPanelLabel               matlab.ui.control.Label
        Epochs_1                        matlab.ui.control.EditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        NumberofClasses_1               matlab.ui.control.EditField
        NumberofClassesEditFieldLabel   matlab.ui.control.Label
        NumberofFeatures_1              matlab.ui.control.EditField
        NumberofFeaturesEditFieldLabel  matlab.ui.control.Label
        NumberofStates_1                matlab.ui.control.EditField
        NumberofStatesEditFieldLabel    matlab.ui.control.Label
        NumberofClauses_1               matlab.ui.control.EditField
        NumberofClausesEditFieldLabel   matlab.ui.control.Label
        Sensitivity_1                   matlab.ui.control.EditField
        SensitivityEditFieldLabel       matlab.ui.control.Label
        Threshold_1                     matlab.ui.control.EditField
        ThresholdTEditFieldLabel        matlab.ui.control.Label
        Sensitivity_2                   matlab.ui.control.EditField
        Threshold_2                     matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = public)
        SelectedFile
        XTrain
        yTrain
        XVal
        yVal
        TrainedModel
        XTest
        yTest
    end
    
    properties (Access = private)
            IsTraining = false;
            stopFlag = false; % ใช้สำหรับกดหยุด
            hTrain
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TrainButton.Enable = 'off';
        end

        % Button pushed function: SelectedFileLabel
        function SelectedFileLabelPushed(app, event)
        [file, path] = uigetfile({ ...
            '*.csv', 'CSV Files (*.csv)'; ...
            '*.xlsx', 'Excel Files (*.xlsx)'}, ...
            'Select a file');
        
        if isequal(file, 0)
            uialert(app.UIFigure, 'คุณยังไม่ได้เลือกไฟล์', 'ข้อผิดพลาด', 'Icon', 'warning');
        else
            app.SelectedFile = fullfile(path, file);
        
            % ✅ แสดงชื่อไฟล์ที่ Label (แทนข้อความในปุ่ม)
            app.Label.Text = file;
        
            app.TrainButton.Enable = 'on';
        
            if endsWith(file, '.csv')
                data = readmatrix(app.SelectedFile);
            elseif endsWith(file, '.xlsx')
                data = readmatrix(app.SelectedFile);
            else
                uialert(app.UIFigure, 'ไม่รองรับไฟล์นี้', 'Error', 'Icon', 'error');
                return;
            end

            % อัพเดตข้อความบนปุ่ม (optional)
            app.Label.Text = file;
            disp("Training file loaded successfully.");
        
            app.yTrain = data(:, 1);
            app.XTrain = data(:, 2:end);
        end

    end

        % ✅ เพิ่มฟังก์ชันใหม่ด้านล่างนี้เลย
        function saveParamsToFile(app, ~)
        % สร้างตารางค่าพารามิเตอร์
        params = {
            'Threshold', app.Threshold_1.Value, app.Threshold_2.Value;
            'Sensitivity', app.Sensitivity_1.Value, app.Sensitivity_2.Value;
            'Number of Clauses', app.NumberofClauses_1.Value;
            'Number of States', app.NumberofStates_1.Value;
            'Number of Features', app.NumberofFeatures_1.Value;
            'Number of Classes', app.NumberofClasses_1.Value;
            'Epochs', app.Epochs_1.Value;
        };
    
        % แปลงเป็นตาราง
        T = cell2table(params, ...
            'VariableNames', {'Parameter', 'Value_1', 'Value_2'});
    
        % เลือก path ไฟล์
        [file, path] = uiputfile('parameters.xlsx', 'Save Parameters As');
        if isequal(file, 0)
            return;
        end
        fullPath = fullfile(path, file);
    
        % ✅ เขียนลงไฟล์ .xlsx
        writetable(T, fullPath);
    
        % Popup สำเร็จ
        uialert(app.UIFigure, 'Parameter saved to Excel successfully!', ...
            'Done', 'Icon', 'success');
        end
        function saveGraphToImage(~, axesHandle, app)
                disp([epoch, acc_train(epoch), acc_val(epoch)]);  % DEBUG
        
                [file, path] = uiputfile({'*.png'; '*.jpg'}, 'Save Graph As', 'graph.png');
                if isequal(file, 0)
                    return;
                end
        
                fullPath = fullfile(path, file);
        
                try
                    exportgraphics(axesHandle, fullPath);
                    uialert(app.UIFigure, 'Graph saved successfully!', 'Done', 'Icon', 'success');
                catch ME
                    uialert(app.UIFigure, "Error saving graph: " + ME.message, 'Error', 'Icon', 'error');
                end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Threshold_1.Value = '';
            app.Threshold_2.Value = '';
            app.Sensitivity_1.Value = '';
            app.Sensitivity_2.Value = '';
            app.NumberofClauses_1.Value = '';
            app.NumberofStates_1.Value = '';
            app.NumberofFeatures_1.Value = '';
            app.NumberofClasses_1.Value = '';
            app.Epochs_1.Value = '';
            app.Label.Text = '';
            app.Label_2.Text = '';
        
            % รีเซ็ตกราฟ
            cla(app.UIAxes);
            title(app.UIAxes, '');
            xlabel(app.UIAxes, 'Epochs');
            ylabel(app.UIAxes, 'Accuracy');
        
            % ดับไฟ
            app.TrainingLamp.Color = [0.5 0.5 0.5];
        
            % หยุดการเทรน
            app.IsTraining = false;
        
            % ล้างไฟล์ Training
            app.SelectedFile = '';
            app.XTrain = [];
            app.yTrain = [];
        
            % ล้างไฟล์ Test
            app.XTest = [];
            app.yTest = [];
        
            % ปิดปุ่ม Train
            app.TrainButton.Enable = 'off';
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            % === เตรียมข้อมูล ===
            X_train = app.XTrain;
            y_train = app.yTrain;
            X_val = app.XVal;
            y_val = app.yVal;
        
            % ถ้า validation ว่าง → ใช้ train แทน
            if isempty(app.XVal) || isempty(app.yVal)
                X_val = app.XTrain;
                y_val = app.yTrain;
            else
                X_val = app.XVal;
                y_val = app.yVal;
            end

            % ตรวจสอบก่อน
            requiredFieldsLeft = { ...
                app.Threshold_1.Value, app.Sensitivity_1.Value, app.NumberofClauses_1.Value, ...
                app.NumberofStates_1.Value, app.NumberofFeatures_1.Value, ...
                app.NumberofClasses_1.Value, app.Epochs_1.Value};
            
            if strcmp(app.Switch2.Value, "Sweep Mode")
                % ต้องเช็คทั้งสองฝั่ง
                requiredFieldsRight = { ...
                    app.Threshold_2.Value, app.Sensitivity_2.Value};
            
                if any(cellfun(@isempty, requiredFieldsLeft)) || any(cellfun(@isempty, requiredFieldsRight))
                    uialert(app.UIFigure, 'กรุณากรอกค่าพารามิเตอร์ให้ครบทั้งสองฝั่งก่อน Train', 'Error', 'Icon', 'error');
                    return;
                end
            else
                % Manual Mode → เช็คแค่ฝั่งซ้าย
                if any(cellfun(@isempty, requiredFieldsLeft))
                    uialert(app.UIFigure, 'กรุณากรอกค่าพารามิเตอร์ให้ครบก่อน Train', 'Error', 'Icon', 'error');
                    return;
                end
            end
        
            % === อ่านพารามิเตอร์ตาม Mode ===
            if strcmp(app.Switch2.Value, "Sweep Mode")
                % 🟡 Sweep Mode: ใช้ช่วงของค่าพารามิเตอร์ Threshold และ Sensitivity
                ThresholdStart   = str2double(app.Threshold_1.Value);
                ThresholdEnd     = str2double(app.Threshold_2.Value);
                ThresholdStep    = str2double(app.ThresholdStep.Value);
            
                SensitivityStart = str2double(app.Sensitivity_1.Value);
                SensitivityEnd   = str2double(app.Sensitivity_2.Value);
                SensitivityStep  = str2double(app.SensitivityStep.Value);
            
                % 🔎 ตรวจสอบค่าขั้นบันได
                if ThresholdStep <= 0 || SensitivityStep <= 0
                    uialert(app.UIFigure, 'ThresholdStep และ SensitivityStep ต้องมากกว่า 0', 'Error', 'Icon', 'error');
                    return;
                end
            
                ThresholdRange   = ThresholdStart:ThresholdStep:ThresholdEnd;
                SensitivityRange = SensitivityStart:SensitivityStep:SensitivityEnd;
            
                NumClauses  = str2double(app.NumberofClauses_1.Value);
                NumStates   = str2double(app.NumberofStates_1.Value);
                NumFeatures = str2double(app.NumberofFeatures_1.Value);
                NumClasses  = str2double(app.NumberofClasses_1.Value);
                Epochs      = str2double(app.Epochs_1.Value);
            
                bestAcc = -inf;
                bestModel = [];
                bestParams = struct();
            
                for Threshold = ThresholdRange
                    for Sensitivity = SensitivityRange
                        tm_tmp = TsetlinMachine(NumClasses, NumClauses, NumFeatures, NumStates, Sensitivity, Threshold, 1);
            
                        for epoch = 1:Epochs
                            for i = 1:size(X_train, 1)
                                target_class = y_train(i) + 1;
                                if isnan(target_class) || target_class < 1 || target_class > NumClasses
                                    error("พบ target_class ไม่ถูกต้อง: %.2f (index %d)", target_class, i);
                                end
                                tm_tmp = tm_tmp.update(X_train(i, :), target_class);
                            end
                        end
            
                        valAcc = tm_tmp.evaluate(X_val, y_val);
                        if valAcc > bestAcc
                            bestAcc = valAcc;
                            bestModel = tm_tmp;
                            bestParams.Threshold = Threshold;
                            bestParams.Sensitivity = Sensitivity;
                        end
                    end
                end
            
                tm = bestModel;
                Threshold = bestParams.Threshold;
                Sensitivity = bestParams.Sensitivity;
            
                disp(['🔍 Sweep Mode: Best Threshold=', num2str(Threshold), ', Sensitivity=', num2str(Sensitivity), ...
                      ', Validation Accuracy=', num2str(bestAcc*100), '%']);
            else
                % 🟢 Manual Mode
                Threshold   = str2double(getParam(app.Threshold_1.Value, app.Threshold_2.Value));
                Sensitivity = str2double(getParam(app.Sensitivity_1.Value, app.Sensitivity_2.Value));
                NumClauses  = str2double(app.NumberofClauses_1.Value);
                NumStates   = str2double(app.NumberofStates_1.Value);
                NumFeatures = str2double(app.NumberofFeatures_1.Value);
                NumClasses  = str2double(app.NumberofClasses_1.Value);
                Epochs      = str2double(app.Epochs_1.Value);
            end

            % 🛡️ ตรวจสอบว่า Epochs เป็นจำนวนเต็มบวก
            if Epochs < 1 || isnan(Epochs)
                uialert(app.UIFigure, 'ค่า Epochs ต้องเป็นจำนวนเต็มบวกมากกว่า 0', 'Error', 'Icon', 'error');
                return;
            end
                    
            % ตรวจ clauses ต่อ class
            if floor(NumClauses / NumClasses) <= 0
                uialert(app.UIFigure, 'จำนวน Clauses ต้องมากกว่าจำนวน Classes หลายเท่า (อย่างน้อย 10x)', 'Error', 'Icon', 'error');
                return;
            end
        
            % === กรอง NaN ใน y_train ===
            valid_idx = ~isnan(y_train);
            X_train = X_train(valid_idx, :);
            y_train = y_train(valid_idx);
        
            % === สร้างหรือดึง Model ===
            if isempty(app.TrainedModel)
                boost_tp = 1;
                tm = TsetlinMachine(NumClasses, NumClauses, NumFeatures, NumStates, Sensitivity, Threshold, boost_tp);
                disp(['✅ โมเดลถูกสร้างแล้ว: Threshold=', num2str(Threshold), ...
                  ', Sensitivity=', num2str(Sensitivity), ...
                  ', Clauses=', num2str(NumClauses), ...
                  ', States=', num2str(NumStates), ...
                  ', Features=', num2str(NumFeatures), ...
                  ', Classes=', num2str(NumClasses)]);
            else
                tm = app.TrainedModel; % ใช้ model เดิม
            end
        
            acc_train = zeros(1, Epochs);
            acc_val = zeros(1, Epochs);
        
            % === เตรียมกราฟ ===
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            xlabel(app.UIAxes, 'Epoch');
            ylabel(app.UIAxes, 'Accuracy');
            title(app.UIAxes, 'Training Accuracy');
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [1 Epochs]);
            ylim(app.UIAxes, [0 1]);
        
            hTrain = plot(app.UIAxes, nan, nan, '-', 'Color', [1 0.5 0], 'DisplayName', 'Train Accuracy');
        
            % === เปิด Lamp ===
            app.TrainingLamp.Color = [1, 1, 0];
        
            % === Train ===
            app.stopFlag = false;

            stopNotice = '';  % กำหนดเริ่มต้นให้ว่าง
        
            for epoch = 1:Epochs
                if app.stopFlag
                    stopNotice = sprintf('Training ถูกหยุดที่ Epoch ที่ %d\n', epoch);
                    break;
                end
        
                for i = 1:size(X_train, 1)
                    target_class = y_train(i) + 1;
                    if isnan(target_class) || target_class < 1 || target_class > NumClasses
                        error("พบ target_class ไม่ถูกต้อง: %.2f (index %d)", target_class, i);
                    end
                    tm = tm.update(X_train(i, :), target_class);
                end
        
                acc_train(epoch) = tm.evaluate(X_train, y_train);
                acc_val(epoch) = tm.evaluate(X_val, y_val);
        
                set(hTrain, 'XData', 1:epoch, 'YData', acc_train(1:epoch));
                drawnow;
            end
        
            % === สรุป accuracy สุดท้าย ===
            % ถ้าเทรนครบ
            if ~app.stopFlag
                final_train_acc = acc_train(epoch) * 100;
            else
                final_train_acc = acc_train(epoch - 1) * 100;
            end
        
            % === Evaluate test accuracy ===
            if ~isempty(app.XTest) && ~isempty(app.yTest)
                acc_test = tm.evaluate(app.XTest, app.yTest);
                disp(['Test Accuracy: ', num2str(acc_test * 100), '%']);
                testStr = sprintf('Final Test Accuracy: %.2f%%', acc_test * 100);
            else
                acc_test = NaN;
                disp("No Test set selected. Skipping test evaluation.");
                testStr = 'Final Test Accuracy: ไม่ได้เลือกไฟล์สำหรับ Test';
            end
        
            % === เปลี่ยน Lamp ===
            app.TrainingLamp.Color = [0, 1, 0];
        
            % === บันทึกกราฟเป็นไฟล์ชั่วคราว ===
            tmpFigFile = [tempname, '.png'];
            exportgraphics(app.UIAxes, tmpFigFile);
        
            % === สร้างข้อความพารามิเตอร์ ===
            if strcmp(app.Switch2.Value, "Sweep Mode")
                paramStr = sprintf([ ...
                    'Sweep Mode :\n' ...
                    'Threshold: %.2f - %.2f  Step : %.2f\n' ...
                    'Sensitivity: %.2f - %.2f  Step : %.2f\n' ...
                    'Clauses: %d\n' ...
                    'States: %d\n' ...
                    'Features: %d\n' ...
                    'Classes: %d\n' ...
                    'Epochs: %d\n\n' ...
                    'Train: %.2f%%\n' ...
                    '%s\n' ...
                    '%s' ], ...
                    str2double(app.Threshold_1.Value), str2double(app.Threshold_2.Value), str2double(app.ThresholdStep.Value), ...
                    str2double(app.Sensitivity_1.Value), str2double(app.Sensitivity_2.Value), str2double(app.SensitivityStep.Value), ...
                    NumClauses, NumStates, NumFeatures, NumClasses, Epochs, ...
                    final_train_acc, testStr, stopNotice);
            else
                paramStr = sprintf([ ...
                'Fix Mode :\n' ...
                'Threshold: %s\n' ...
                'Sensitivity: %s\n' ...
                'Number of Clauses: %s\n' ...
                'Number of States: %s\n' ...
                'Number of Features: %s\n' ...
                'Number of Classes: %s\n' ...
                'Epochs: %s\n\n' ...
                'Final Train Accuracy: %.2f%%\n' ...
                'Final Test Accuracy: %.2f%%\n' ...
                '%s' ], ...
                app.Threshold_1.Value, ...
                app.Sensitivity_1.Value, ...
                app.NumberofClauses_1.Value, ...
                app.NumberofStates_1.Value, ...
                app.NumberofFeatures_1.Value, ...
                app.NumberofClasses_1.Value, ...
                app.Epochs_1.Value, ...
                final_train_acc, acc_test * 100, stopNotice);
            end
        
            % === สร้าง popup window ===
            popupFig = uifigure('Name', 'Training Result', 'Position', [100 100 450 650]);
        
            ax = uiaxes(popupFig, 'Position', [50 350 350 250]);
            imshow(tmpFigFile, 'Parent', ax);
            ax.Visible = 'off';
        
            uitextarea(popupFig, ...
                'Value', splitlines(paramStr), ...
                'Position', [50 130 350 200], ...
                'Editable', 'off');
        
            uibutton(popupFig, 'push', ...
                'Position', [50 70 150 30], ...
                'Text', 'Save Graph', ...
                'ButtonPushedFcn', @(btn, event) saveGraph());
        
            uibutton(popupFig, 'push', ...
                'Position', [250 70 150 30], ...
                'Text', 'Save Parameter', ...
                'ButtonPushedFcn', @(btn, event) saveParams());
        
            app.TrainedModel = tm;
        
            % === Nested function: Save Graph ===
            function saveGraph()
                [file, path] = uiputfile('graph.png', 'Save Graph As');
                if isequal(file, 0)
                    return;
                end
                exportgraphics(app.UIAxes, fullfile(path, file));
                uialert(app.UIFigure, 'Graph saved successfully!', 'Done', 'Icon', 'success');
            end
        
            % === Nested function: Save Parameter ===
            function saveParams()
                params = {
                    'Threshold', num2str(Threshold);
                    'Sensitivity', num2str(Sensitivity);
                    'Number of Clauses', num2str(NumClauses);
                    'Number of States', num2str(NumStates);
                    'Number of Features', num2str(NumFeatures);
                    'Number of Classes', num2str(NumClasses);
                    'Epochs', num2str(Epochs);
                };
                T = cell2table(params, 'VariableNames', {'Parameter', 'Value'});
        
                [file, path] = uiputfile('parameters.xlsx', 'Save Parameters As');
                if isequal(file, 0)
                    return;
                end
                writetable(T, fullfile(path, file));
                uialert(app.UIFigure, 'Parameter saved to Excel successfully!', 'Done', 'Icon', 'success');
            end
        
            % === Nested function: Get Param ===
            function val = getParam(leftVal, rightVal)
                if ~isempty(strtrim(rightVal))
                    val = rightVal;
                else
                    val = leftVal;
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % ดับไฟ (เปลี่ยนสี Lamp เป็นเทา)
            app.TrainingLamp.Color = [0.5, 0.5, 0.5]; % สีเทา
            
            % ตั้งค่า flag ให้หยุด
            app.stopFlag = true;
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            mode = app.Switch2.Value;
        
            % List ชื่อ component ด้านขวา
            rightInputs = [
                app.Threshold_2, app.Sensitivity_2
            ];
        
            % === Reset ค่าทุกช่องเหมือนกด Reset ===
            resetAllInputs(app);
        
            % === แสดง/ซ่อนช่องด้านขวา ===
                if strcmp(mode, 'Fix Mode')
                % ❌ ซ่อนช่องฝั่งขวาทั้งหมด
                app.Threshold_2.Visible = false;
                app.Sensitivity_2.Visible = false;
                app.ThresholdStep.Visible = false;
                app.SensitivityStep.Visible = false;
            else
                % ✅ แสดงช่องฝั่งขวาทั้งหมด
                app.Threshold_2.Visible = true;
                app.Sensitivity_2.Visible = true;
                app.ThresholdStep.Visible = true;
                app.SensitivityStep.Visible = true;
            end
        end
        
        % === ฟังก์ชัน Reset ช่องทั้งหมด ===
        function resetAllInputs(app)
            % ล้างค่าฝั่งซ้าย
            app.Threshold_1.Value = '';
            app.Sensitivity_1.Value = '';
            app.NumberofClauses_1.Value = '';
            app.NumberofStates_1.Value = '';
            app.NumberofFeatures_1.Value = '';
            app.NumberofClasses_1.Value = '';
            app.Epochs_1.Value = '';
        
            % ล้างค่าฝั่งขวา
            app.Threshold_2.Value = '';
            app.Sensitivity_2.Value = '';
        
            % ล้างกราฟ
            cla(app.UIAxes);
        
            % ปิด Lamp
            app.TrainingLamp.Color = [0.5, 0.5, 0.5];
        
            % เคลียร์ Model
            app.TrainedModel = [];
        end

        % Button pushed function: SelectTestFileButton
        function SelectTestFileButtonPushed(app, event)
        [file, path] = uigetfile({ ...
            '*.csv', 'CSV Files (*.csv)'; ...
            '*.xlsx', 'Excel Files (*.xlsx)'}, ...
            'Select a test file');
        
        if isequal(file, 0)
            uialert(app.UIFigure, 'คุณยังไม่ได้เลือกไฟล์ทดสอบ', 'ข้อผิดพลาด', 'Icon', 'warning');
        else
            testFile = fullfile(path, file);
        
            % ✅ แสดงชื่อไฟล์ที่ Label ด้านขวา
            app.Label_2.Text = file;
        
            if endsWith(file, '.csv')
                data = readmatrix(testFile);
            elseif endsWith(file, '.xlsx')
                data = readmatrix(testFile);
            else
                uialert(app.UIFigure, 'ไม่รองรับไฟล์นี้', 'Error', 'Icon', 'error');
                return;
            end
        
            app.yTest = data(:, 1);
            app.XTest = data(:, 2:end);
        
                % อัพเดตข้อความบนปุ่ม (optional)
                app.Label_2.Text = file;
                disp("Test file loaded successfully.");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Epochs')
            ylabel(app.UIAxes, 'Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.Threshold_2 = uieditfield(app.UIFigure, 'text');
            app.Sensitivity_2 = uieditfield(app.UIFigure, 'text');
            app.ThresholdTEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdTEditFieldLabel.Text = 'Threshold (T):';
            app.Threshold_1 = uieditfield(app.UIFigure, 'text');
            app.SensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity :';
            app.Sensitivity_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClausesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClausesEditFieldLabel.Text = 'Number of Clauses :';
            app.NumberofClauses_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofStatesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofStatesEditFieldLabel.Text = 'Number of States :';
            app.NumberofStates_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofFeaturesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFeaturesEditFieldLabel.Text = 'Number of Features :';
            app.NumberofFeatures_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClassesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClassesEditFieldLabel.Text = 'Number of Classes :';
            app.NumberofClasses_1 = uieditfield(app.UIFigure, 'text');
            app.EpochsEditFieldLabel = uilabel(app.UIFigure);
            app.EpochsEditFieldLabel.Text = 'Epochs :';
            app.Epochs_1 = uieditfield(app.UIFigure, 'text');
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.Items = {'Sweep Mode', 'Fix Mode'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Value = 'Sweep Mode';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TrainingLampLabel = uilabel(app.UIFigure);
            app.TrainingLampLabel.Text = 'Training';
            app.TrainingLamp = uilamp(app.UIFigure);
            app.SelectedFileLabel = uibutton(app.UIFigure, 'push');
            app.SelectedFileLabel.ButtonPushedFcn = createCallbackFcn(app, @SelectedFileLabelPushed, true);
            app.SelectedFileLabel.Text = 'Select File Training';
            app.SelectTestFileButton = uibutton(app.UIFigure, 'push');
            app.SelectTestFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTestFileButtonPushed, true);
            app.SelectTestFileButton.Text = 'Select Test File';
            app.ThresholdStep = uieditfield(app.UIFigure, 'text');
            app.SensitivityStep = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TsetlinGUI_With_Call_Model_2_Selectfile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end