classdef TsetlinGUI_With_Call_Model_2_Selectfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StepLabel_2                     matlab.ui.control.Label
        ToLabel_2                       matlab.ui.control.Label
        StepLabel                       matlab.ui.control.Label
        ToLabel                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SensitivityStep                 matlab.ui.control.EditField
        ThresholdStep                   matlab.ui.control.EditField
        SelectTestFileButton            matlab.ui.control.Button
        SelectedFileLabel               matlab.ui.control.Button
        TrainingLamp                    matlab.ui.control.Lamp
        TrainingLampLabel               matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        Switch2                         matlab.ui.control.Switch
        ControlPanelLabel               matlab.ui.control.Label
        Epochs_1                        matlab.ui.control.EditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        NumberofClasses_1               matlab.ui.control.EditField
        NumberofClassesEditFieldLabel   matlab.ui.control.Label
        NumberofFeatures_1              matlab.ui.control.EditField
        NumberofFeaturesEditFieldLabel  matlab.ui.control.Label
        NumberofStates_1                matlab.ui.control.EditField
        NumberofStatesEditFieldLabel    matlab.ui.control.Label
        NumberofClauses_1               matlab.ui.control.EditField
        NumberofClausesEditFieldLabel   matlab.ui.control.Label
        Sensitivity_1                   matlab.ui.control.EditField
        SensitivityEditFieldLabel       matlab.ui.control.Label
        Threshold_1                     matlab.ui.control.EditField
        ThresholdTEditFieldLabel        matlab.ui.control.Label
        Sensitivity_2                   matlab.ui.control.EditField
        Threshold_2                     matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = public)
        SelectedFile
        XTrain
        yTrain
        XVal
        yVal
        TrainedModel
        XTest
        yTest
    end
    
    properties (Access = private)
            IsTraining = false;
            stopFlag = false; % ใช้สำหรับกดหยุด
            hTrain     matlab.graphics.chart.primitive.Line
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TrainButton.Enable = 'off';
        end

        % Button pushed function: SelectedFileLabel
        function SelectedFileLabelPushed(app, event)
        [file, path] = uigetfile({ ...
            '*.csv', 'CSV Files (*.csv)'; ...
            '*.xlsx', 'Excel Files (*.xlsx)'}, ...
            'Select a file');
        
        if isequal(file, 0)
            uialert(app.UIFigure, 'คุณยังไม่ได้เลือกไฟล์', 'ข้อผิดพลาด', 'Icon', 'warning');
        else
            app.SelectedFile = fullfile(path, file);
        
            % ✅ แสดงชื่อไฟล์ที่ Label (แทนข้อความในปุ่ม)
            app.Label.Text = file;
        
            app.TrainButton.Enable = 'on';
        
            if endsWith(file, '.csv')
                data = readmatrix(app.SelectedFile);
            elseif endsWith(file, '.xlsx')
                data = readmatrix(app.SelectedFile);
            else
                uialert(app.UIFigure, 'ไม่รองรับไฟล์นี้', 'Error', 'Icon', 'error');
                return;
            end

            % อัพเดตข้อความบนปุ่ม (optional)
            app.Label.Text = file;
            disp("Training file loaded successfully.");
        
            app.yTrain = data(:, 1);
            app.XTrain = data(:, 2:end);
        end

    end

        % ✅ เพิ่มฟังก์ชันใหม่ด้านล่างนี้เลย
        function saveParamsToFile(app, ~)
        % สร้างตารางค่าพารามิเตอร์
        params = {
            'Threshold', app.EditField.Value, app.EditField.Value;
            'Sensitivity', app.EditField.Value, app.EditField.Value;
            'Number of Clauses', app.EditField2.Value;
            'Number of States', app.EditField3.Value;
            'Number of Features', app.EditField4.Value;
            'Number of Classes', app.EditField5.Value;
            'Epochs', app.EditField.Value;
        };
    
        % แปลงเป็นตาราง
        T = cell2table(params, ...
            'VariableNames', {'Parameter', 'Value_1', 'Value_2'});
    
        % เลือก path ไฟล์
        [file, path] = uiputfile('parameters.xlsx', 'Save Parameters As');
        if isequal(file, 0)
            return;
        end
        fullPath = fullfile(path, file);
    
        % ✅ เขียนลงไฟล์ .xlsx
        writetable(T, fullPath);
    
        % Popup สำเร็จ
        uialert(app.UIFigure, 'Parameter saved to Excel successfully!', ...
            'Done', 'Icon', 'success');
        end
        function saveGraphToImage(~, axesHandle, app)
                disp([epoch, acc_train(epoch), acc_val(epoch)]);  % DEBUG
        
                [file, path] = uiputfile({'*.png'; '*.jpg'}, 'Save Graph As', 'graph.png');
                if isequal(file, 0)
                    return;
                end
        
                fullPath = fullfile(path, file);
        
                try
                    exportgraphics(axesHandle, fullPath);
                    uialert(app.UIFigure, 'Graph saved successfully!', 'Done', 'Icon', 'success');
                catch ME
                    uialert(app.UIFigure, "Error saving graph: " + ME.message, 'Error', 'Icon', 'error');
                end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % ==== ยกเลิกการเทรนที่อาจกำลังรันอยู่ ====
            app.stopFlag   = true;      % แจ้งให้ลูปเทรนหยุด
            app.IsTraining = false;     % เผื่อมีการใช้ flag นี้ที่อื่น
            drawnow;                    % ให้ UI รับรู้การเปลี่ยนแปลงทันที
        
            % ==== เคลียร์ค่าพารามิเตอร์ฝั่งซ้าย ====
            app.Threshold_1.Value        = '';
            app.Sensitivity_1.Value      = '';
            app.NumberofClauses_1.Value  = '';
            app.NumberofStates_1.Value   = '';
            app.NumberofFeatures_1.Value = '';
            app.NumberofClasses_1.Value  = '';
            app.Epochs_1.Value           = '';
        
            % ==== เคลียร์ค่าพารามิเตอร์ฝั่งขวา (ช่วง/สเต็ป) ====
            app.Threshold_2.Value        = '';
            app.Sensitivity_2.Value      = '';
            app.ThresholdStep.Value      = '';
            app.SensitivityStep.Value    = '';
        
            % ==== เคลียร์ชื่อไฟล์ที่แสดง ====
            app.Label.Text   = '';   % ชื่อไฟล์ Train
            app.Label_2.Text = '';   % ชื่อไฟล์ Test
        
            % ==== ล้างข้อมูล Train/Val/Test และไฟล์ที่เลือก ====
            app.SelectedFile = '';
            app.XTrain = []; app.yTrain = [];
            app.XVal   = []; app.yVal   = [];
            app.XTest  = []; app.yTest  = [];
        
            % ==== ล้างโมเดลที่เทรนไว้ ====
            app.TrainedModel = [];
        
            % ==== รีเซ็ตกราฟ ====
            cla(app.UIAxes);
            hold(app.UIAxes, 'off');
            grid(app.UIAxes, 'on');
            title(app.UIAxes, '');
            xlabel(app.UIAxes, 'Epoch');    % ให้สอดคล้องกับฟังก์ชันเทรนล่าสุด
            ylabel(app.UIAxes, 'Accuracy');
            drawnow;
        
            % ==== ดับไฟสถานะ ====
            app.TrainingLamp.Color = [0.5 0.5 0.5];   % เทา
        
            % ==== ปิดปุ่ม Train จนกว่าจะเลือกไฟล์ใหม่ ====
            app.TrainButton.Enable = 'off';
        
            % ==== จัดการการเปิด/ปิดช่องฝั่งขวาตามโหมดปัจจุบัน ====
            if strcmp(app.Switch2.Value, 'Fix Mode')
                app.Threshold_2.Enable     = 'off';
                app.Sensitivity_2.Enable   = 'off';
                app.ThresholdStep.Enable   = 'off';
                app.SensitivityStep.Enable = 'off';
        
                app.ToLabel.Enable         = 'off';
                app.StepLabel.Enable       = 'off';
                app.ToLabel_2.Enable       = 'off';
                app.StepLabel_2.Enable     = 'off';
            else
                % Sweep Mode
                app.Threshold_2.Enable     = 'on';
                app.Sensitivity_2.Enable   = 'on';
                app.ThresholdStep.Enable   = 'on';
                app.SensitivityStep.Enable = 'on';
        
                app.ToLabel.Enable         = 'on';
                app.StepLabel.Enable       = 'on';
                app.ToLabel_2.Enable       = 'on';
                app.StepLabel_2.Enable     = 'on';
            end
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            % === เตรียมข้อมูลเริ่มต้น ===
            X_train = app.XTrain;   y_train = app.yTrain;
            X_val   = app.XVal;     y_val   = app.yVal;
        
            % === ตรวจค่าจาก UI ที่จำเป็น ===
            requiredLeft = { ...
                app.Threshold_1.Value, app.Sensitivity_1.Value, ...
                app.NumberofClauses_1.Value, app.NumberofStates_1.Value, ...
                app.NumberofFeatures_1.Value, app.NumberofClasses_1.Value, ...
                app.Epochs_1.Value };
        
            if strcmp(app.Switch2.Value, "Sweep Mode")
                requiredRight = { ...
                    app.Threshold_2.Value, app.Sensitivity_2.Value, ...
                    app.ThresholdStep.Value, app.SensitivityStep.Value };
                if any(cellfun(@isempty, requiredLeft)) || any(cellfun(@isempty, requiredRight))
                    uialert(app.UIFigure,'กรุณากรอกค่าพารามิเตอร์ให้ครบทั้งสองฝั่งก่อน Train','Error','Icon','error'); 
                    return;
                end
            else
                if any(cellfun(@isempty, requiredLeft))
                    uialert(app.UIFigure,'กรุณากรอกค่าพารามิเตอร์ให้ครบก่อน Train','Error','Icon','error'); 
                    return;
                end
            end
        
            % === อ่านพารามิเตอร์ตามโหมด ===
            if strcmp(app.Switch2.Value, "Sweep Mode")
                ThresholdStart   = str2double(app.Threshold_1.Value);
                ThresholdEnd     = str2double(app.Threshold_2.Value);
                ThresholdStep    = str2double(app.ThresholdStep.Value);
        
                SensitivityStart = str2double(app.Sensitivity_1.Value);
                SensitivityEnd   = str2double(app.Sensitivity_2.Value);
                SensitivityStep  = str2double(app.SensitivityStep.Value);
        
                if any(isnan([ThresholdStart,ThresholdEnd,ThresholdStep,SensitivityStart,SensitivityEnd,SensitivityStep])) ...
                   || ThresholdStep <= 0 || SensitivityStep <= 0
                    uialert(app.UIFigure,'ค่าของช่วง/สเต็ปไม่ถูกต้อง','Error','Icon','error'); 
                    return;
                end
        
                TRange = ThresholdStart:ThresholdStep:ThresholdEnd;
                SRange = SensitivityStart:SensitivityStep:SensitivityEnd;
        
                NumClauses  = str2double(app.NumberofClauses_1.Value);
                NumStates   = str2double(app.NumberofStates_1.Value);
                NumFeatures = str2double(app.NumberofFeatures_1.Value);
                NumClasses  = str2double(app.NumberofClasses_1.Value);
                Epochs      = str2double(app.Epochs_1.Value);
            else
                % Fix Mode
                Threshold   = str2double(getParam(app.Threshold_1.Value, app.Threshold_2.Value));
                Sensitivity = str2double(getParam(app.Sensitivity_1.Value, app.Sensitivity_2.Value));
                NumClauses  = str2double(app.NumberofClauses_1.Value);
                NumStates   = str2double(app.NumberofStates_1.Value);
                NumFeatures = str2double(app.NumberofFeatures_1.Value);
                NumClasses  = str2double(app.NumberofClasses_1.Value);
                Epochs      = str2double(app.Epochs_1.Value);
            end
        
            % === ตรวจความสมเหตุสมผล ===
            if isnan(Epochs) || Epochs < 1 || mod(Epochs,1) ~= 0
                uialert(app.UIFigure,'ค่า Epochs ต้องเป็นจำนวนเต็มบวกมากกว่า 0','Error','Icon','error'); 
                return;
            end
            if isnan(NumClauses) || isnan(NumClasses) || floor(NumClauses/NumClasses) <= 0
                uialert(app.UIFigure,'จำนวน Clauses ต้องมากกว่าจำนวน Classes หลายเท่า (อย่างน้อย 10x)','Error','Icon','error'); 
                return;
            end
        
            % === กรอง NaN ใน y_train ===
            valid_idx = ~isnan(y_train);
            X_train = X_train(valid_idx,:); 
            y_train = y_train(valid_idx);
        
            % === ถ้าไม่มี Validation → ทำ hold-out 20% แบบ stratified ===
            if isempty(X_val) || isempty(y_val)
                X_all = X_train; y_all = y_train;
                classes = unique(y_all(~isnan(y_all)));
                idx_train = []; idx_val = [];
                val_ratio = 0.20;
        
                rng('shuffle');
                for c = classes'
                    idx_c = find(y_all == c);
                    idx_c = idx_c(randperm(numel(idx_c)));
                    nval  = max(1, round(val_ratio * numel(idx_c)));
                    idx_val   = [idx_val;   idx_c(1:nval)];
                    idx_train = [idx_train; idx_c(nval+1:end)];
                end
                idx_train = unique(idx_train,'stable');
                if isempty(idx_train)
                    uialert(app.UIFigure,'ไม่สามารถแบ่ง hold-out ได้: ตัวอย่างต่อคลาสน้อยเกินไป','Error','Icon','error');
                    return;
                end
        
                X_val   = X_all(idx_val, :);   y_val   = y_all(idx_val);
                X_train = X_all(idx_train, :); y_train = y_all(idx_train);
            end
        
            % === เตรียมกราฟ (แสดงเฉพาะ Train Acc) ===
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            xlabel(app.UIAxes,'Epoch'); ylabel(app.UIAxes,'Accuracy');
            title(app.UIAxes,'Training Accuracy'); grid(app.UIAxes,'on');
        
            % สำหรับ Sweep: คำนวณจำนวน epoch ทั้งหมดที่ "ต้องรันจริง"
            if strcmp(app.Switch2.Value, "Sweep Mode")
                totalPlannedEpochs = numel(TRange) * numel(SRange) * Epochs;
            else
                totalPlannedEpochs = Epochs;
            end
            xlim(app.UIAxes,[1 totalPlannedEpochs]); 
            ylim(app.UIAxes,[0 1]);
            hTrain = plot(app.UIAxes, nan, nan, '-');     % ไม่ตั้ง DisplayName/legend
            drawnow;
        
            % === เปิดไฟสถานะ ===
            app.TrainingLamp.Color = [1 1 0]; % เหลือง = กำลังเทรน
        
            % === Train ===
            app.stopFlag = false;
            stopNotice = '';
            wasStopped = false;
        
            % จะเก็บ Train accuracy แบบ "ต่อเนื่อง" ตามจำนวน epoch จริงทั้งหมด
            accTrainSeries = nan(1, totalPlannedEpochs);
            globalEp = 0;          % ตัวนับ epoch รวมทั้งหมด (ต่อเนื่อง)
            epStopped = 0;         % epoch ที่หยุดจริง (ตาม globalEp)
        
            bestValAcc = -inf; bestModel = []; bestT = NaN; bestS = NaN;
            lastT = NaN;          lastS = NaN;
        
            if strcmp(app.Switch2.Value, "Sweep Mode")
                outerBreak = false;
                for T = TRange
                    if app.stopFlag, outerBreak = true; break; end
                    for S = SRange
                        if app.stopFlag, outerBreak = true; break; end
        
                        lastT = T; lastS = S;
                        tm_tmp = TsetlinMachine(NumClasses, NumClauses, NumFeatures, NumStates, S, T, 1);
        
                        for ep = 1:Epochs
                            globalEp = globalEp + 1;     % ✅ นับ epoch รวมแบบต่อเนื่อง
                            if app.stopFlag
                                wasStopped = true;
                                epStopped  = globalEp;
                                stopNotice = sprintf('⚠ Training stopped at Epoch %d\n', epStopped);
                                outerBreak = true;
                                break;
                            end
        
                            % อัปเดตโมเดล
                            for i = 1:size(X_train,1)
                                target_class = y_train(i) + 1;
                                tm_tmp = tm_tmp.update(X_train(i,:), target_class);
                            end
        
                            % เก็บค่า train acc ลง series ต่อเนื่อง
                            accTrainSeries(globalEp) = tm_tmp.evaluate(X_train, y_train);
        
                            % วาดกราฟต่อเนื่อง
                            set(hTrain,'XData',1:globalEp,'YData',accTrainSeries(1:globalEp));
                            if mod(globalEp, 10)==0, drawnow limitrate; end
                        end
        
                        % ค่าประเมินของคู่นี้
                        thisVal = tm_tmp.evaluate(X_val, y_val);
                        if thisVal > bestValAcc
                            bestValAcc = thisVal;
                            bestModel  = tm_tmp;
                            bestT      = T; 
                            bestS      = S;
                        end
        
                        if outerBreak, break; end
                    end
                    if outerBreak, break; end
                end
        
                % เลือกโมเดลที่จะรายงาน
                if ~isempty(bestModel)
                    tm = bestModel;  Threshold = bestT; Sensitivity = bestS;
                elseif exist('tm_tmp','var')
                    % หยุดก่อนเลือก best → ใช้โมเดลล่าสุดที่กำลังรันอยู่
                    tm = tm_tmp;     Threshold = lastT; Sensitivity = lastS;
                    bestValAcc = tm.evaluate(X_val, y_val);
                else
                    uialert(app.UIFigure,'Training ถูกยกเลิกก่อนเริ่มเทรน','Info','Icon','warning');
                    return;
                end
        
            else
                % Fix Mode (นับ globalEp ต่อเนื่องเช่นกัน แต่มีรุ่นเดียว)
                tm = TsetlinMachine(NumClasses, NumClauses, NumFeatures, NumStates, Sensitivity, Threshold, 1);
                for ep = 1:Epochs
                    globalEp = globalEp + 1;
                    if app.stopFlag
                        wasStopped = true;
                        epStopped  = globalEp;
                        stopNotice = sprintf('⚠ Training stopped at Epoch %d\n', epStopped);
                        break;
                    end
        
                    for i = 1:size(X_train,1)
                        target_class = y_train(i) + 1;
                        tm = tm.update(X_train(i,:), target_class);
                    end
        
                    accTrainSeries(globalEp) = tm.evaluate(X_train, y_train);
                    set(hTrain,'XData',1:globalEp,'YData',accTrainSeries(1:globalEp));
                    if mod(globalEp,10)==0, drawnow limitrate; end
                end
                bestValAcc = tm.evaluate(X_val, y_val);
            end
        
            % ถ้าไม่ได้หยุด ให้ถือว่ารันครบ
            if ~wasStopped
                epStopped = globalEp;   % = totalPlannedEpochs เมื่อจบครบ
            end
        
            % === สรุปผล (โมเดลสุดท้าย) ===
            final_train_acc = tm.evaluate(X_train, y_train) * 100;
            final_val_acc   = tm.evaluate(X_val,   y_val)   * 100;
        
            if ~isempty(app.XTest) && ~isempty(app.yTest)
                acc_test = tm.evaluate(app.XTest, app.yTest) * 100;
                testStr  = sprintf('Final Test Accuracy: %.2f%%', acc_test);
            else
                acc_test = NaN;
                testStr  = 'Final Test Accuracy: ไม่ได้เลือกไฟล์สำหรับ Test';
            end
        
            app.TrainingLamp.Color = [0 1 0]; % เขียว = เสร็จสิ้น
        
            % === แสดงผลลัพธ์ใน popup ===
            tmpFigFile = [tempname, '.png']; 
            exportgraphics(app.UIAxes, tmpFigFile);
        
            % บรรทัด Epoch (แสดงเฉพาะเมื่อไม่หยุด)
            epochLine = '';
            if ~wasStopped
                epochLine = sprintf('Epochs: %d\n', epStopped);  % สำหรับ Sweep = จำนวน epoch จริงทั้งหมด
            end
        
            if strcmp(app.Switch2.Value, "Sweep Mode")
                paramStr = sprintf([ ...
                    'Sweep Mode :\n' ...
                    'Threshold Range: %.4g to %.4g (Step: %.4g)\n' ...
                    'Sensitivity Range: %.4g to %.4g (Step: %.4g)\n' ...
                    'Best Threshold: %.4g\nBest Sensitivity: %.4g\n' ...
                    'Clauses: %d\nStates: %d\nFeatures: %d\nClasses: %d\n' ...
                    '%s' ...  % epochLine (ว่างเมื่อกด Stop)
                    '\nFinal Train Accuracy: %.2f%%\n%s\n%s' ], ...
                    ThresholdStart,ThresholdEnd,ThresholdStep, ...
                    SensitivityStart,SensitivityEnd,SensitivityStep, ...
                    Threshold,Sensitivity, ...
                    NumClauses,NumStates,NumFeatures,NumClasses, ...
                    epochLine, ...
                    final_train_acc, testStr, stopNotice);
            else
                paramStr = sprintf([ ...
                    'Fix Mode :\n' ...
                    'Threshold: %s\nSensitivity: %s\n' ...
                    'Number of Clauses: %s\nNumber of States: %s\n' ...
                    'Number of Features: %s\nNumber of Classes: %s\n' ...
                    '%s' ...  % epochLine
                    '\nFinal Train Accuracy: %.2f%%\nFinal Test Accuracy: %s\n%s' ], ...
                    app.Threshold_1.Value,app.Sensitivity_1.Value, ...
                    app.NumberofClauses_1.Value,app.NumberofStates_1.Value, ...
                    app.NumberofFeatures_1.Value,app.NumberofClasses_1.Value, ...
                    epochLine, ...
                    final_train_acc, ...
                    iif(~isnan(acc_test), sprintf('%.2f%%', acc_test), 'ไม่ได้เลือกไฟล์สำหรับ Test'), ...
                    stopNotice);
            end
        
            popupFig = uifigure('Name','Training Result','Position',[100 100 520 720]);
            ax = uiaxes(popupFig,'Position',[60 410 400 260]); 
            imshow(tmpFigFile,'Parent',ax); 
            ax.Visible = 'off';
        
            uitextarea(popupFig,'Value',splitlines(paramStr),'Position',[60 150 400 240],'Editable','off');
        
            uibutton(popupFig,'push','Position',[60 90 180 32],'Text','Save Graph', ...
                'ButtonPushedFcn', @(~,~) saveGraph());
            uibutton(popupFig,'push','Position',[280 90 180 32],'Text','Save Parameter', ...
                'ButtonPushedFcn', @(~,~) saveParams());
        
            app.TrainedModel = tm;
        
            % === Nested helpers ===
            function saveGraph()
                [file, path] = uiputfile('graph.png','Save Graph As');
                if isequal(file,0), return; end
                exportgraphics(app.UIAxes, fullfile(path, file));
                uialert(app.UIFigure,'Graph saved successfully!','Done','Icon','success');
            end
        
            function saveParams()
                % แถวทั่วไปตามโหมด
                if strcmp(app.Switch2.Value,"Sweep Mode")
                    rows = {
                        'Mode','Sweep Mode'
                        'Threshold Range Start', num2str(ThresholdStart)
                        'Threshold Range End',   num2str(ThresholdEnd)
                        'Threshold Step',        num2str(ThresholdStep)
                        'Sensitivity Range Start', num2str(SensitivityStart)
                        'Sensitivity Range End',   num2str(SensitivityEnd)
                        'Sensitivity Step',        num2str(SensitivityStep)
                        'Best Threshold (used)',   num2str(Threshold)
                        'Best Sensitivity (used)', num2str(Sensitivity)
                        'Best Validation Accuracy (%)', num2str(bestValAcc*100,'%.4f')
                        'Number of Clauses', num2str(NumClauses)
                        'Number of States',  num2str(NumStates)
                        'Number of Features',num2str(NumFeatures)
                        'Number of Classes', num2str(NumClasses)
                    };
                else
                    rows = {
                        'Mode','Fix Mode'
                        'Threshold (used)', num2str(Threshold)
                        'Sensitivity (used)', num2str(Sensitivity)
                        'Number of Clauses', num2str(NumClauses)
                        'Number of States',  num2str(NumStates)
                        'Number of Features',num2str(NumFeatures)
                        'Number of Classes', num2str(NumClasses)
                    };
                end
        
                % เพิ่ม Epochs เฉพาะเมื่อไม่ได้หยุด
                if ~wasStopped
                    rows = [rows; {'Epochs', num2str(epStopped)}]; %#ok<AGROW>
                end
        
                % เติมผลลัพธ์ท้ายตาราง
                rows = [rows; {
                    'Final Train Accuracy (%)', num2str(final_train_acc,'%.4f')
                    'Final Val Accuracy (%)',   num2str(final_val_acc,'%.4f')
                    'Final Test Accuracy (%)',  iif(~isnan(acc_test),num2str(acc_test,'%.4f'),'N/A')
                    'Training File', safeText(app.Label.Text)
                    'Test File',     safeText(app.Label_2.Text)
                    'Timestamp',     char(datetime('now','Format','yyyy-MM-dd HH:mm:ss'))
                }];
        
                T = cell2table(rows,'VariableNames',{'Parameter','Value'});
                [file,path] = uiputfile('parameters.xlsx','Save Parameters As');
                if isequal(file,0), return; end
                writetable(T, fullfile(path,file));
                uialert(app.UIFigure,'Parameter saved to Excel successfully!','Done','Icon','success');
            end
        
            function val = getParam(leftVal,rightVal)
                if ~isempty(strtrim(rightVal)), val = rightVal; else, val = leftVal; end
            end
            function out = iif(cond,a,b)
                if cond, out=a; else, out=b; end
            end
            function s = safeText(x)
                if isempty(x), s=''; else, s=char(string(x)); end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % ดับไฟ (เปลี่ยนสี Lamp เป็นเทา)
            app.TrainingLamp.Color = [0.5, 0.5, 0.5]; % สีเทา
            
            % ตั้งค่า flag ให้หยุด
            app.stopFlag = true;
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            mode = app.Switch2.Value;

            % === Reset ค่าทุกช่องเหมือนกด Reset ===
            resetAllInputs(app);
        
            % === แสดง/ซ่อนช่องด้านขวา ===
                if strcmp(mode, 'Fix Mode')
                    % 🚫 ปิดการแก้ไข (ทุกช่อง + label จางลง)
                    app.Threshold_2.Enable    = 'off';
                    app.Sensitivity_2.Enable  = 'off';
                    app.ThresholdStep.Enable  = 'off';
                    app.SensitivityStep.Enable= 'off';
                
                    app.ToLabel.Enable        = 'off';
                    app.StepLabel.Enable      = 'off';
                    app.ToLabel_2.Enable      = 'off';
                    app.StepLabel_2.Enable    = 'off';
                
                else
                    % ✅ เปิดใช้งานปกติ
                    app.Threshold_2.Enable    = 'on';
                    app.Sensitivity_2.Enable  = 'on';
                    app.ThresholdStep.Enable  = 'on';
                    app.SensitivityStep.Enable= 'on';
                
                    app.ToLabel.Enable        = 'on';
                    app.StepLabel.Enable      = 'on';
                    app.ToLabel_2.Enable      = 'on';
                    app.StepLabel_2.Enable    = 'on';
                end
        end
        
        % === ฟังก์ชัน Reset ช่องทั้งหมด ===
        function resetAllInputs(app)
            % ฝั่งซ้าย
            app.Threshold_1.Value        = '';
            app.Sensitivity_1.Value      = '';
            app.NumberofClauses_1.Value  = '';
            app.NumberofStates_1.Value   = '';
            app.NumberofFeatures_1.Value = '';
            app.NumberofClasses_1.Value  = '';
            app.Epochs_1.Value           = '';
        
            % ฝั่งขวา (ช่วง)
            app.Threshold_2.Value        = '';
            app.Sensitivity_2.Value      = '';
        
            % Step
            app.ThresholdStep.Value      = '';
            app.SensitivityStep.Value    = '';
        
            % ล้างกราฟ
            cla(app.UIAxes);
            title(app.UIAxes,'');
            xlabel(app.UIAxes,'Epoch');
            ylabel(app.UIAxes,'Accuracy');
            grid(app.UIAxes,'on');
        
            % ปิด Lamp
            app.TrainingLamp.Color = [0.5 0.5 0.5];
        
            % ล้างโมเดล (ไม่ยุ่งกับไฟล์ที่เลือก/ปุ่ม Train)
            app.TrainedModel = [];
        end

        % Button pushed function: SelectTestFileButton
        function SelectTestFileButtonPushed(app, event)
        [file, path] = uigetfile({ ...
            '*.csv', 'CSV Files (*.csv)'; ...
            '*.xlsx', 'Excel Files (*.xlsx)'}, ...
            'Select a test file');
        
        if isequal(file, 0)
            uialert(app.UIFigure, 'คุณยังไม่ได้เลือกไฟล์ทดสอบ', 'ข้อผิดพลาด', 'Icon', 'warning');
        else
            testFile = fullfile(path, file);
        
            % ✅ แสดงชื่อไฟล์ที่ Label ด้านขวา
            app.Label_2.Text = file;
        
            if endsWith(file, '.csv')
                data = readmatrix(testFile);
            elseif endsWith(file, '.xlsx')
                data = readmatrix(testFile);
            else
                uialert(app.UIFigure, 'ไม่รองรับไฟล์นี้', 'Error', 'Icon', 'error');
                return;
            end
        
            app.yTest = data(:, 1);
            app.XTest = data(:, 2:end);
        
                % อัพเดตข้อความบนปุ่ม (optional)
                app.Label_2.Text = file;
                disp("Test file loaded successfully.");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Epochs')
            ylabel(app.UIAxes, 'Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.Threshold_2 = uieditfield(app.UIFigure, 'text');
            app.Sensitivity_2 = uieditfield(app.UIFigure, 'text');
            app.ThresholdTEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdTEditFieldLabel.Text = 'Threshold (T):';
            app.Threshold_1 = uieditfield(app.UIFigure, 'text');
            app.SensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity :';
            app.Sensitivity_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClausesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClausesEditFieldLabel.Text = 'Number of Clauses :';
            app.NumberofClauses_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofStatesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofStatesEditFieldLabel.Text = 'Number of States :';
            app.NumberofStates_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofFeaturesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFeaturesEditFieldLabel.Text = 'Number of Features :';
            app.NumberofFeatures_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClassesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClassesEditFieldLabel.Text = 'Number of Classes :';
            app.NumberofClasses_1 = uieditfield(app.UIFigure, 'text');
            app.EpochsEditFieldLabel = uilabel(app.UIFigure);
            app.EpochsEditFieldLabel.Text = 'Epochs :';
            app.Epochs_1 = uieditfield(app.UIFigure, 'text');
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.Items = {'Sweep Mode', 'Fix Mode'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Value = 'Sweep Mode';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TrainingLampLabel = uilabel(app.UIFigure);
            app.TrainingLampLabel.Text = 'Training';
            app.TrainingLamp = uilamp(app.UIFigure);
            app.SelectedFileLabel = uibutton(app.UIFigure, 'push');
            app.SelectedFileLabel.ButtonPushedFcn = createCallbackFcn(app, @SelectedFileLabelPushed, true);
            app.SelectedFileLabel.Text = 'Select Training File';
            app.SelectTestFileButton = uibutton(app.UIFigure, 'push');
            app.SelectTestFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTestFileButtonPushed, true);
            app.SelectTestFileButton.Text = 'Select Test File';
            app.ThresholdStep = uieditfield(app.UIFigure, 'text');
            app.SensitivityStep = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To';
            app.StepLabel = uilabel(app.UIFigure);
            app.StepLabel.Text = 'Step :';
            app.ToLabel_2 = uilabel(app.UIFigure);
            app.ToLabel_2.Text = 'To';
            app.StepLabel_2 = uilabel(app.UIFigure);
            app.StepLabel_2.Text = 'Step :';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TsetlinGUI_With_Call_Model_2_Selectfile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end