classdef TsetlinGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SweepOrderLabel                 matlab.ui.control.Label
        SweepOrder                      matlab.ui.control.DropDown
        ShowEpochs                      matlab.ui.control.EditField
        ShowEpochsLabel                 matlab.ui.control.Label
        SensitivityState                matlab.ui.control.Label
        ThresholdState                  matlab.ui.control.Label
        TrainLabel                      matlab.ui.control.Label
        StepLabel_2                     matlab.ui.control.Label
        ToLabel_2                       matlab.ui.control.Label
        StepLabel                       matlab.ui.control.Label
        ToLabel                         matlab.ui.control.Label
        TestfileLabel                   matlab.ui.control.Label
        SensitivityStep                 matlab.ui.control.EditField
        ThresholdStep                   matlab.ui.control.EditField
        TrainingfileLabel               matlab.ui.control.Label
        SelectTestFileButton            matlab.ui.control.Button
        SelectedFileLabel               matlab.ui.control.Button
        TrainLamp                       matlab.ui.control.Lamp
        ResetButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        Switch2                         matlab.ui.control.Switch
        Epochs_1                        matlab.ui.control.EditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        NumberofClasses_1               matlab.ui.control.EditField
        NumberofClassesEditFieldLabel   matlab.ui.control.Label
        NumberofFeatures_1              matlab.ui.control.EditField
        NumberofFeaturesEditFieldLabel  matlab.ui.control.Label
        NumberofStates_1                matlab.ui.control.EditField
        NumberofStatesEditFieldLabel    matlab.ui.control.Label
        NumberofClauses_1               matlab.ui.control.EditField
        NumberofClausesEditFieldLabel   matlab.ui.control.Label
        Sensitivity_1                   matlab.ui.control.EditField
        SensitivityEditFieldLabel       matlab.ui.control.Label
        Threshold_1                     matlab.ui.control.EditField
        ThresholdTEditFieldLabel        matlab.ui.control.Label
        Sensitivity_2                   matlab.ui.control.EditField
        Threshold_2                     matlab.ui.control.EditField
        ControlPanelLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

        properties (Access = public)
            SelectedFile
            XTrain
            yTrain
            TrainedModel
            XTest
            yTest
        end
        
        properties (Access = private)
            IsTraining = false
            stopFlag = false % ใช้สำหรับกดหยุด
            hTrain     matlab.graphics.chart.primitive.Line
            LampTimer  % ใช้หน่วง 3 วิ ก่อนเปลี่ยนเป็นสีเทา
        end
    
        % ===== Helpers =====
        methods (Access = private)
            function [M, errmsg] = readFlexibleFile(app, filepath)
                % อ่านเป็น numeric matrix แบบยืดหยุ่น:
                % 1) ลอง readmatrix (CSV/Excel มาตรฐาน)
                % 2) Fallback: ไฟล์ที่ทั้งบรรทัดถูกครอบด้วย "..." และคั่นด้วยช่องว่าง
                errmsg = "";
                M = [];
            
                % ---------- 1) พยายามอ่านแบบปกติ ----------
                try
                    M = readmatrix(filepath);
                    if ~isempty(M) && size(M,2) >= 2 && isnumeric(M) && all(isfinite(M), 'all')
                        return;
                    end
                catch
                    % ไปต่อ fallback
                end
            
                % ---------- 2) Fallback: robust whitespace + quotes ----------
                try
                    L = readlines(filepath);              % string array ทีละบรรทัด
            
                    % ลบ BOM (ถ้ามี) เฉพาะบรรทัดแรก
                    if ~isempty(L)
                        L(1) = replace(L(1), string(char([239 187 191])), "");  % UTF-8 BOM
                    end
            
                    % ตัดช่องว่างหัวท้าย และทิ้งบรรทัดว่าง/ missing
                    L = strtrim(L);
                    L = L(~ismissing(L) & strlength(L) > 0);
            
                    % ตัดเครื่องหมายคำพูดที่ครอบทั้งบรรทัด: "...." -> ....
                    L = regexprep(L, '^"(.*)"$', '$1');
            
                    % ทำช่องว่างให้เหลือช่องเดียว (กันเคสมีหลายช่อง/แท็บปน)
                    L = regexprep(L, '\s+', ' ');
            
                    % split ด้วยช่องว่างเดียว
                    tokens = regexp(L, ' ', 'split');
            
                    % หาบรรทัดอ้างอิง (มี token >= 2)
                    lens = cellfun(@numel, tokens);
                    anchorIdx = find(lens >= 2, 1, 'first');
                    if isempty(anchorIdx)
                        error("No valid data rows (after cleaning).");
                    end
                    ncols = lens(anchorIdx);
            
                    % อนุญาตทิ้งบรรทัดที่ไม่ตรง (เช่น ท้ายไฟล์ว่าง/เพี้ยน)
                    keep = (lens == ncols);
                    tokens = tokens(keep);
            
                    % แปลงเป็นตัวเลขและประกอบเป็นเมทริกซ์
                    rowsNum = cellfun(@(r) str2double(string(r)), tokens, 'UniformOutput', false);
                    M = vertcat(rowsNum{:});
            
                    if isempty(M) || any(isnan(M), 'all')
                        error("Non-numeric tokens found or empty content.");
                    end
                catch ME
                    M = [];
                    errmsg = ME.message;
                end
            end
            
            function [X,y] = splitXY_auto(app, M)
                % เดาว่า label อยู่คอลัมน์แรกหรือสุดท้ายแบบอัตโนมัติ
                c1 = M(:,1); cN = M(:,end);
                if isIntVector(app, cN) && numel(unique(cN)) >= 2 && ~isBinaryColumn(app, cN)
                    y = cN;  X = M(:,1:end-1);
                elseif isIntVector(app, c1) && numel(unique(c1)) >= 2 && ~isBinaryColumn(app, c1)
                    y = c1;  X = M(:,2:end);
                else
                    % fallback: เอาคอลัมน์สุดท้ายเป็น label
                    y = cN;  X = M(:,1:end-1);
                end
            end
            
            function tf = isBinaryColumn(~, v)
                v = v(~isnan(v));
                tf = ~isempty(v) && all(v==0 | v==1);
            end
            
            function tf = isIntVector(~, v)
                v = v(~isnan(v));
                tf = ~isempty(v) && all(mod(v,1)==0);
            end

            function setLampGray(app)
                if ~app.IsTraining
                    app.TrainLamp.Color = [0.5 0.5 0.5]; % เทา
                end
            end
    
            function setControlsEnabled(app, tf)
                % tf = true -> enable controls (respect current mode)
                % tf = false -> disable everything relevant during training
                state = tern(app, tf, 'on', 'off');
    
                % ------- กลุ่มปุ่ม/ไฟล์/ปุ่มรีเซ็ต -------
                app.Switch2.Enable              = state;
                app.SelectedFileLabel.Enable    = state;   % Select Training File
                app.SelectTestFileButton.Enable = state;   % Select Test File
                app.ResetButton.Enable          = state;
            
                % ------- ฝั่งซ้าย -------
                app.Threshold_1.Enable          = state;
                app.Sensitivity_1.Enable        = state;
                app.NumberofClauses_1.Enable    = state;
                app.NumberofStates_1.Enable     = state;
                app.NumberofFeatures_1.Enable   = state;
                app.NumberofClasses_1.Enable    = state;
                app.Epochs_1.Enable             = state;
            
                % ------- ฝั่งขวา (ขึ้นกับโหมด) -------
                if ~tf
                    rstate = 'off';  % ระหว่างเทรน ปิดทั้งหมด
                else
                    if strcmp(app.Switch2.Value,'Sweep Mode')
                        rstate = 'on';   % หลังเทรน + Sweep -> เปิดได้
                    else
                        rstate = 'off';  % หลังเทรน + Fix -> คงปิดไว้
                    end
                end
                app.Threshold_2.Enable          = rstate;
                app.Sensitivity_2.Enable        = rstate;
                app.ThresholdStep.Enable        = rstate;
                app.SensitivityStep.Enable      = rstate;
                app.ToLabel.Enable              = rstate;
                app.StepLabel.Enable            = rstate;
                app.ToLabel_2.Enable            = rstate;
                app.StepLabel_2.Enable          = rstate;
    
                % ------- Sweep order (ขึ้นกับโหมด + tf) -------
                if tf && strcmp(app.Switch2.Value,'Sweep Mode')
                    app.SweepOrder.Enable = 'on';
                    app.SweepOrderLabel.Enable = 'on';
                else
                    app.SweepOrder.Enable = 'off';
                    app.SweepOrderLabel.Enable = 'on';
                end
            end
    
            function out = tern(~,cond,a,b)
                if cond, out=a; else, out=b; end
            end
    
            function showTS(app, tf)
                vis = tern(app, tf, 'on', 'off');
                app.ThresholdState.Visible   = vis;
                app.SensitivityState.Visible = vis;
            end
            
            function setStateTS(app, T, S)
                if nargin>=2, app.ThresholdState.Text  = sprintf('Threshold : %.2f', T); end
                if nargin>=3, app.SensitivityState.Text = sprintf('Sensitivity : %.2f', S); end
            end

            function restoreUI(app)
                % ซ่อนสถานะ T,S
                showTS(app, false);
            
                % ปรับสถานะทั่วไป
                app.IsTraining          = false;
                app.TrainLabel.Text     = 'Train';
                app.TrainLamp.Color     = [0.5 0.5 0.5];
                app.StopButton.Enable   = 'off';
                app.TrainButton.Enable  = 'on';
            
                % เปิดสวิตช์/ปุ่มเลือกไฟล์/รีเซ็ต
                app.Switch2.Enable              = 'on';
                app.SelectedFileLabel.Enable    = 'on';
                app.SelectTestFileButton.Enable = 'on';
                app.ResetButton.Enable          = 'on';
            
                % เปิดช่องกรอกตามโหมด
                setControlsEnabled(app, true);
            
                drawnow;
            end

            function resetInputsKeepFiles(app)
                % เคลียร์ช่องตัวเลข/ช่วง/สเต็ปเท่านั้น
                app.Threshold_1.Value        = '';
                app.Sensitivity_1.Value      = '';
                app.NumberofClauses_1.Value  = '';
                app.NumberofStates_1.Value   = '';
                app.NumberofFeatures_1.Value = '';
                app.NumberofClasses_1.Value  = '';
                app.Epochs_1.Value           = '';
            
                app.Threshold_2.Value        = '';
                app.Sensitivity_2.Value      = '';
                app.ThresholdStep.Value      = '';
                app.SensitivityStep.Value    = '';
            
                % ไม่แตะต้อง: app.TrainingfileLabel, app.TestfileLabel, app.SelectedFile, X/Y train/val/test
                % จะไม่ไปล้างไฟล์ที่เลือกไว้
            end

            function acc = evaluateLikeClass(app, tm, X, y)
                % ทำให้ label เป็น 0-based ถ้าตอนโหลด dataset มาเป็น 1-based
                if min(y) == 1
                    y = y - 1;
                end
                num_samples = size(X, 1);
                correct = 0;
                for i = 1:num_samples
                    pred = tm.predict(X(i, :));
                    if pred == y(i)
                        correct = correct + 1;
                    end
                end
                acc = correct / num_samples;   % คืนค่าเป็น 0..1
            end

        end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TrainButton.Enable = 'off';
            app.StopButton.Enable  = 'off';
            app.ThresholdState.Visible  = 'off';
            app.SensitivityState.Visible = 'off';
            if isempty(app.ShowEpochs.Value), app.ShowEpochs.Value = '10'; end
        
            % ข้อความเริ่มต้นข้างปุ่มเลือกไฟล์
            app.TrainingfileLabel.Text = 'Training file has not been selected.';
            app.TestfileLabel.Text     = 'Test file has not been selected.';
        
            % ตั้งสถานะ enable เริ่มต้นตามโหมด
            setControlsEnabled(app, true);
        end

        % Button pushed function: SelectedFileLabel
        function SelectedFileLabelPushed(app, event)
            [file, path] = uigetfile({ ...
                '*.csv',  'CSV Files (*.csv)'; ...
                '*.xlsx', 'Excel Files (*.xlsx)'}, ...
                'Select a file');
        
            if isequal(file, 0)
                uialert(app.UIFigure, 'You have not selected a file.', 'Error', 'Icon', 'warning');
                return;
            end
        
            app.SelectedFile = fullfile(path, file);
        
            % โชว์ชื่อไฟล์
            app.TrainingfileLabel.Text = file;
        
            % อ่านไฟล์แบบยืดหยุ่น (CSV ปกติ, Excel, หรือไฟล์ที่เป็น "..." + ช่องว่าง)
            [data, errmsg] = readFlexibleFile(app, app.SelectedFile);
            if isempty(data)
                uialert(app.UIFigure, "Failed to read training file: " + errmsg, 'Error', 'Icon','error');
                app.TrainButton.Enable = 'off';
                return;
            end
        
            if size(data,2) < 2
                uialert(app.UIFigure,'The file must have at least 2 columns (features + label).','Invalid file format','Icon','error');
                app.TrainButton.Enable = 'off';
                return;
            end
        
            % แยก X/Y อัตโนมัติ (รองรับกรณี label อยู่หัว/ท้าย)
            [X, y] = splitXY_auto(app, data);
            app.XTrain = X;
            app.yTrain = y;
        
            % อัปเดตช่องแสดงจำนวนฟีเจอร์/คลาส (ถ้ามี)
            try
                app.NumberofFeatures_1.Value = num2str(size(X,2));
                app.NumberofClasses_1.Value  = num2str(numel(unique(y)));
            catch
                % ช่องนี้ไม่จำเป็นต้องมี
            end
        
            app.TrainButton.Enable = 'on';
            disp("Training file loaded successfully.");
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % ==== ยกเลิกการเทรนที่อาจกำลังรันอยู่ ====
            app.stopFlag   = true;
            app.IsTraining = false;
            drawnow;
        
            % ==== เคลียร์ค่าพารามิเตอร์ฝั่งซ้าย ====
            app.Threshold_1.Value        = '';
            app.Sensitivity_1.Value      = '';
            app.NumberofClauses_1.Value  = '';
            app.NumberofStates_1.Value   = '';
            app.NumberofFeatures_1.Value = '';
            app.NumberofClasses_1.Value  = '';
            app.Epochs_1.Value           = '';
        
            % ==== เคลียร์ค่าพารามิเตอร์ฝั่งขวา (ช่วง/สเต็ป) ====
            app.Threshold_2.Value        = '';
            app.Sensitivity_2.Value      = '';
            app.ThresholdStep.Value      = '';
            app.SensitivityStep.Value    = '';
        
            % ==== เคลียร์ชื่อไฟล์ที่แสดง ====
            app.TrainingfileLabel.Text = 'Training file has not been selected.';
            app.TestfileLabel.Text     = 'Test file has not been selected.';
        
            % ==== ล้างข้อมูล Train/Val/Test และไฟล์ที่เลือก ====
            app.SelectedFile = '';
            app.XTrain = []; app.yTrain = [];
            app.XTest  = []; app.yTest  = [];
        
            % ==== ล้างโมเดลที่เทรนไว้ ====
            app.TrainedModel = [];
        
            % ==== รีเซ็ตกราฟ ====
            cla(app.UIAxes);
            hold(app.UIAxes, 'off');
            grid(app.UIAxes, 'on');
            title(app.UIAxes, '');
            xlabel(app.UIAxes, 'Epoch');
            ylabel(app.UIAxes, 'Accuracy');
            drawnow;
        
            % ==== ดับไฟสถานะ ====
            app.TrainLamp.Color = [0.5 0.5 0.5];
        
            % ==== ปิดปุ่ม Train จนกว่าจะเลือกไฟล์ใหม่ ====
            app.TrainButton.Enable = 'off';
            app.StopButton.Enable  = 'off';

            % เปิด/ปิดช่องตามโหมด
            setControlsEnabled(app, true);

            % รีเซ็ตข้อความสถานะ
            showTS(app, false);
            app.ThresholdState.Text   = 'Threshold :';
            app.SensitivityState.Text = 'Sensitivity :';
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            % ต้องมี Training file เสมอ
            if isempty(app.SelectedFile) || isempty(app.XTrain) || isempty(app.yTrain)
                app.TrainingfileLabel.Text = 'Training file has not been selected.';
                uialert(app.UIFigure,'Training file has not been selected.','Missing file','Icon','warning');
                return;
            end
        
            % Test file ไม่บังคับ ถ้าไม่มีให้โชว์ข้อความที่ Label
            if isempty(app.XTest) || isempty(app.yTest)
                app.TestfileLabel.Text = 'Test file has not been selected.';
            end
        
            % === Fix random seed ===
            rng('shuffle');     % สุ่ม seed ใหม่ทุกครั้งที่กด Train
        
            % === เตรียมข้อมูลเริ่มต้น ===
            X_train = app.XTrain;   
            y_train = app.yTrain;
        
            % === กรอง NaN ใน y_train ===
            valid_idx = ~isnan(y_train);
            X_train = X_train(valid_idx,:);  
            y_train = y_train(valid_idx);
        
            % === ทำ label ให้เป็น 0-based และเป็นคอลัมน์ ===
            y_train = toZeroBased(y_train);
            if isrow(y_train), y_train = y_train(:); end
        
            % === เตรียม y_test (0-based) ถ้ามี ===
            y_test0 = [];
            if ~isempty(app.yTest)
                y_test0 = toZeroBased(app.yTest);
                if isrow(y_test0), y_test0 = y_test0(:); end
            end
        
            % === จำนวนคลาสจากข้อมูลจริง ===
            pool = y_train;
            if ~isempty(y_test0), pool = [pool; y_test0]; end
            NumClassesEff = max(pool) + 1;
            if NumClassesEff < 2
                uialert(app.UIFigure,'The number of classes is less than 2. Please check the label.','Error','Icon','error');
                restoreUI();
                return;
            end
        
            % === ตรวจค่าจาก UI ที่จำเป็น ===
            requiredLeft = { ...
                app.Threshold_1.Value, app.Sensitivity_1.Value, ...
                app.NumberofClauses_1.Value, app.NumberofStates_1.Value, ...
                app.Epochs_1.Value };
        
            if strcmp(app.Switch2.Value, "Sweep Mode")
                requiredRight = { ...
                    app.Threshold_2.Value, app.Sensitivity_2.Value, ...
                    app.ThresholdStep.Value, app.SensitivityStep.Value };
                if any(cellfun(@isempty, requiredLeft)) || any(cellfun(@isempty, requiredRight))
                    uialert(app.UIFigure,'Please fill in the parameters on both sides before Train.','Error','Icon','error');
                    return;
                end
            else
                if any(cellfun(@isempty, requiredLeft))
                    uialert(app.UIFigure,'Please fill in all parameters before Train.','Error','Icon','error');
                    return;
                end
            end
        
            % === อ่านพารามิเตอร์ตามโหมด ===
            if strcmp(app.Switch2.Value, "Sweep Mode")
                ThresholdStart   = str2double(app.Threshold_1.Value);
                ThresholdEnd     = str2double(app.Threshold_2.Value);
                ThresholdStep    = str2double(app.ThresholdStep.Value);
        
                SensitivityStart = str2double(app.Sensitivity_1.Value);
                SensitivityEnd   = str2double(app.Sensitivity_2.Value);
                SensitivityStep  = str2double(app.SensitivityStep.Value);
        
                if any(isnan([ThresholdStart,ThresholdEnd,ThresholdStep,SensitivityStart,SensitivityEnd,SensitivityStep])) ...
                   || ThresholdStep <= 0 || SensitivityStep <= 0
                    uialert(app.UIFigure,'The range/step value is invalid.','Error','Icon','error');
                    return;
                end
                if ThresholdStart > ThresholdEnd || SensitivityStart > SensitivityEnd
                    uialert(app.UIFigure,'The Start value must not be greater than the End value.','Error','Icon','error');
                    return;
                end
        
                TRange = ThresholdStart:ThresholdStep:ThresholdEnd;
                SRange = SensitivityStart:SensitivityStep:SensitivityEnd;
        
                NumClauses  = str2double(app.NumberofClauses_1.Value);
                NumStates   = str2double(app.NumberofStates_1.Value);
                NumFeatures = size(X_train, 2);            % ใช้ฟีเจอร์จริงจากข้อมูล
                Epochs      = str2double(app.Epochs_1.Value);
            else
                % Fix Mode
                Threshold   = str2double(getParam(app.Threshold_1.Value, app.Threshold_2.Value));
                Sensitivity = str2double(getParam(app.Sensitivity_1.Value, app.Sensitivity_2.Value));
                NumClauses  = str2double(app.NumberofClauses_1.Value);
                NumStates   = str2double(app.NumberofStates_1.Value);
                NumFeatures = size(X_train, 2);            % ใช้ฟีเจอร์จริงจากข้อมูล
                Epochs      = str2double(app.Epochs_1.Value);
            end
        
            % === ตรวจความสมเหตุสมผล ===
            if isnan(Epochs) || Epochs < 1 || mod(Epochs,1) ~= 0
                uialert(app.UIFigure,'Epochs must be a positive integer greater than 0.','Error','Icon','error');
                return;
            end
            if isnan(NumClauses)
                uialert(app.UIFigure,'The number of Clauses is incorrect.','Error','Icon','error');
                return;
            end
        
            % === อ่านค่า ShowEpochs ===
            if ~isprop(app,'ShowEpochs') || isempty(strtrim(string(app.ShowEpochs.Value)))
                uialert(app.UIFigure, 'Please enter the value "Show Epochs".', 'Invalid Show Epochs', 'Icon','error');
                return;
            end
            showStep = str2double(app.ShowEpochs.Value);
            if isnan(showStep) || mod(showStep,1)~=0 || showStep < 1 || showStep > Epochs
                uialert(app.UIFigure, sprintf('The "Show Epochs" value must be an integer from 1 to %d', Epochs), ...
                    'Invalid Show Epochs','Icon','error');
                return;
            end
            showStep = floor(showStep);
        
            % === เตรียมกราฟ ===
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            xlabel(app.UIAxes,'Epoch'); ylabel(app.UIAxes,'Accuracy');
            title(app.UIAxes,'Training Accuracy'); grid(app.UIAxes,'on');
            xlim(app.UIAxes,[1 Epochs]); ylim(app.UIAxes,[0 1]);
            app.hTrain = plot(app.UIAxes, NaN, NaN, 'b-', 'LineWidth', 2);
            drawnow;
        
            % === เปิดไฟสถานะ + ปรับปุ่ม ===
            app.TrainLamp.Color = [1 1 0];
            app.TrainLabel.Text = 'Training';
            app.StopButton.Enable  = 'on';
            app.TrainButton.Enable = 'off';
            app.IsTraining         = true;
        
            % ล็อคการเปลี่ยนโหมด/เลือกไฟล์/รีเซ็ต + ฟิลด์ต่าง ๆ
            app.Switch2.Enable = 'off';
            app.SelectedFileLabel.Enable    = 'off';
            app.SelectTestFileButton.Enable = 'off';
            app.ResetButton.Enable          = 'off';
            setControlsEnabled(app, false);
        
            % แสดงค่า T,S
            showTS(app, true);
            if strcmp(app.Switch2.Value,'Sweep Mode')
                app.ThresholdState.Text   = 'T = -';
                app.SensitivityState.Text = 'S = -';
            else
                app.ThresholdState.Text   = sprintf('T = %.2f', Threshold);
                app.SensitivityState.Text = sprintf('S = %.2f', Sensitivity);
            end
            drawnow limitrate;
        
            % === Train (try/catch เพื่อคืน UI เสมอ) ===
            try
                app.stopFlag = false;
                stopNotice = '';
                wasStopped = false;
                epStopped = 0;
            
                % ตัวแปรสำหรับ SWEEP
                bestScore     = -inf;
                bestModel     = [];
                bestT         = NaN;
                bestS         = NaN;
                bestAccCurve  = [];
                bestAccEpochs = [];
            
                if strcmp(app.Switch2.Value,'Sweep Mode')
                    % ---------- SWEEP MODE ----------
                    order = app.SweepOrder.Value;   % 'T→S' หรือ 'S→T'
                    outerBreak = false;
            
                    switch order
                        case 'T→S'      % ให้ S เป็นวงนอก, T เป็นวงใน
                            for S = SRange
                                if app.stopFlag, outerBreak = true; break; end
                                for T = TRange
                                    if trainOnePair(T,S), outerBreak = true; break; end
                                end
                                if outerBreak, break; end
                            end
                        case 'S→T'      % ให้ T เป็นวงนอก, S เป็นวงใน
                            for T = TRange
                                if app.stopFlag, outerBreak = true; break; end
                                for S = SRange
                                    if trainOnePair(T,S), outerBreak = true; break; end
                                end
                                if outerBreak, break; end
                            end
                    end
            
                    % ต้องมีโมเดลที่ดีที่สุด มิฉะนั้น tm จะไม่ถูกกำหนด
                    if isempty(bestModel)
                        restoreUI();
                        uialert(app.UIFigure,'Training was canceled before any model completed.','Info','Icon','warning');
                        return;
                    end
            
                    % <<< สำคัญ: กำหนด tm จากผลลัพธ์ที่ดีที่สุด >>>
                    tm = bestModel;
                    Threshold   = bestT;
                    Sensitivity = bestS;
            
                    % วาดกราฟคู่ที่ดีที่สุด
                    cla(app.UIAxes); hold(app.UIAxes,'on'); grid(app.UIAxes,'on');
                    xlabel(app.UIAxes,'Epoch'); ylabel(app.UIAxes,'Accuracy');
                    title(app.UIAxes, sprintf('Best Training Accuracy (T=%.4g, S=%.4g)', bestT, bestS));
                    xlim(app.UIAxes,[1 Epochs]); ylim(app.UIAxes,[0 1]);
                    app.hTrain = plot(app.UIAxes, bestAccEpochs, bestAccCurve, '-');
                    drawnow;
            
                else
                    % ---------- FIX MODE ----------
                    tm = TsetlinMachine(NumClassesEff, NumClauses, NumFeatures, NumStates, Sensitivity, Threshold, 0);
                    accTrainSeries = nan(1, Epochs);
                    for ep = 1:Epochs
                        if app.stopFlag
                            wasStopped = true;
                            epStopped  = ep;
                            stopNotice = sprintf('⚠ Training stopped at Epoch %d (Threshold = %.2f, Sensitivity = %.2f)\n', ...
                                                 epStopped, Threshold, Sensitivity);
                            break;
                        end
                        for i = 1:size(X_train,1)
                            target_class = y_train(i) + 1;
                            tm = tm.update(X_train(i,:), target_class);
                        end
                        accTrainSeries(ep) = tm.evaluate(X_train, y_train);
                        if mod(ep,showStep)==0 || ep==1 || ep==Epochs
                            x = 1:showStep:ep; if x(end)~=ep, x=[x ep]; end
                            set(app.hTrain,'XData',x,'YData',accTrainSeries(x));
                            drawnow limitrate;
                        end
                    end
                end
            
                if ~wasStopped, epStopped = Epochs; end
            
                % ตอนนี้ tm ถูกกำหนดแน่นอนแล้ว
                final_train_acc = tm.evaluate(X_train, y_train) * 100;
                if ~isempty(app.XTest) && ~isempty(app.yTest)
                    acc_test = tm.evaluate(app.XTest, y_test0) * 100;
                else
                    acc_test = NaN;
                end
        
                % === คืน UI แล้วค่อยเปลี่ยนไฟเขียวถ้าจบครบ ===
                restoreUI();
                if ~wasStopped, app.TrainLamp.Color = [0 1 0]; end
        
                % (เก็บค่าไว้เฉย ๆ)
                if ~strcmp(app.Switch2.Value,'Sweep Mode')
                    app.ThresholdState.Text   = sprintf('T = %.2f', Threshold);
                    app.SensitivityState.Text = sprintf('S = %.2f', Sensitivity);
                else
                    app.ThresholdState.Text   = sprintf('T = %.2f', bestT);
                    app.SensitivityState.Text = sprintf('S = %.2f', bestS);
                end
        
                % === ทำ popup ผลลัพธ์ ===
                tmpFigFile = [tempname, '.png'];
                exportgraphics(app.UIAxes, tmpFigFile, 'Resolution', 220);
        
                % โชว์ว่าจบที่กี่ epoch หรือหยุดที่ไหน (รวม T,S)
                if wasStopped
                    % กรณีหยุดกลางทาง: โชว์ค่าที่กรอก + ข้อความหยุด (มี Epoch/T/S อยู่แล้วใน stopNotice)
                    epochLine = sprintf('Epochs (requested): %d\n%s', Epochs, stopNotice);
                else
                    % จบครบตามที่กรอก
                    if epStopped == Epochs
                        epochLine = sprintf('Epochs: %d\n', Epochs);
                    else
                        % จบไม่ครบ: โชว์ทั้งที่กรอกและที่ทำเสร็จจริง
                        epochLine = sprintf('Epochs (requested): %d\n', Epochs, epStopped);
                    end
                end
        
                if strcmp(app.Switch2.Value, "Sweep Mode")
                    paramStr = sprintf([ ...
                        'Sweep Mode :\n' ...
                        'Threshold Range: %s to %s (Step: %s)\n' ...
                        'Sensitivity Range: %s to %s (Step: %s)\n' ...
                        'Best Threshold: %.4g - Best Sensitivity: %.4g\n' ...
                        'Clauses: %d\nStates: %d\nFeatures: %d\nClasses: %d\n' ...
                        '%s' ...
                        '\nFinal Train Accuracy: %.2f%%\n%s' ], ...
                        app.Threshold_1.Value, app.Threshold_2.Value, app.ThresholdStep.Value, ...
                        app.Sensitivity_1.Value, app.Sensitivity_2.Value, app.SensitivityStep.Value, ...
                        bestT, bestS, ...
                        NumClauses, NumStates, NumFeatures, NumClassesEff, ...
                        epochLine, ...
                        final_train_acc, ...
                        iif(~isnan(acc_test), sprintf('Final Test Accuracy: %.2f%%', acc_test), ...
                           'Final Test Accuracy: No file selected for Test'));
                else
                    paramStr = sprintf([ ...
                        'Fix Mode :\n' ...
                        'Threshold: %s\nSensitivity: %s\n' ...
                        'Number of Clauses: %s\nNumber of States: %s\n' ...
                        'Number of Features: %d\nNumber of Classes: %d\n' ...
                        '%s' ...
                        '\nFinal Train Accuracy: %.2f%%\n%s' ], ...
                        app.Threshold_1.Value, app.Sensitivity_1.Value, ...
                        app.NumberofClauses_1.Value, app.NumberofStates_1.Value, ...
                        NumFeatures, NumClassesEff, ...
                        epochLine, ...
                        final_train_acc, ...
                        iif(~isnan(acc_test), sprintf('Final Test Accuracy: %.2f%%', acc_test), ...
                           'Final Test Accuracy: No file selected for Test'));
                end
        
                figW = 757; figH = 612;
                M = 28; gap1 = 5; gap2 = 24; btnGap = 24;
                axH = 280; axW = figW - 2*M; axX = M; axY = figH - M - axH;
                msgW = 320; msgH = 200;
                cx = @(w) (figW - w)/2;
                msgX = cx(msgW); msgY = axY - gap1 - msgH;
                btnW = 140; btnH = 34; btnY = msgY - gap2 - btnH;
                leftX  = cx(2*btnW + btnGap);
                rightX = leftX + btnW + btnGap;
        
                popupFig = uifigure('Name','Training Result', 'Position',[100 100 figW figH]);
                ax = uiaxes(popupFig, 'Position', [axX axY axW axH]);
                imshow(tmpFigFile, 'Parent', ax); ax.Visible = 'off';
        
                uitextarea(popupFig, 'Value', splitlines(paramStr), ...
                    'Position', [msgX msgY msgW msgH], 'Editable', 'off');
        
                uibutton(popupFig,'push','Text','Save Graph', ...
                    'Position',[leftX btnY btnW btnH], 'ButtonPushedFcn', @(~,~) saveGraph());
                uibutton(popupFig,'push','Text','Save Parameter', ...
                    'Position',[rightX btnY btnW btnH], 'ButtonPushedFcn', @(~,~) saveParams());
        
                app.TrainedModel = tm;
        
            catch ME
                restoreUI();
                uialert(app.UIFigure, "Error while training: " + ME.message, 'Error','Icon','error');
                return;
            end
        
            % ===== Nested helpers =====
            function restoreUI()
                showTS(app, false);
                app.IsTraining          = false;
                app.TrainLabel.Text     = 'Train';
                app.TrainLamp.Color     = [0.5 0.5 0.5];
                app.StopButton.Enable   = 'off';
                app.TrainButton.Enable  = 'on';
        
                app.Switch2.Enable              = 'on';
                app.SelectedFileLabel.Enable    = 'on';
                app.SelectTestFileButton.Enable = 'on';
                app.ResetButton.Enable          = 'on';
        
                setControlsEnabled(app, true);
                drawnow;
            end
        
            function stopped = trainOnePair(T,S)
                stopped = false;
                app.ThresholdState.Text   = sprintf('T = %.2f', T);
                app.SensitivityState.Text = sprintf('S = %.2f', S);
                drawnow limitrate;
        
                % กราฟของคู่ปัจจุบัน (เส้นเดียว)
                cla(app.UIAxes);
                hold(app.UIAxes, 'on');
                xlabel(app.UIAxes,'Epoch'); ylabel(app.UIAxes,'Accuracy');
                title(app.UIAxes,'Training Accuracy'); grid(app.UIAxes,'on');
                xlim(app.UIAxes,[1 Epochs]); ylim(app.UIAxes,[0 1]);
                app.hTrain = plot(app.UIAxes, NaN, NaN, 'b-', 'LineWidth', 2);
        
                tm_tmp = TsetlinMachine(NumClassesEff, NumClauses, NumFeatures, NumStates, S, T, 0);
        
                % เก็บเส้นของคู่ปัจจุบัน
                accCurveTmp = nan(1, Epochs);
                lastEp = 0;
        
                for ep = 1:Epochs
                    if app.stopFlag
                        wasStopped = true;
                        epStopped  = ep;
                        stopNotice = sprintf('⚠ Training stopped at Epoch %d (Threshold = %.2f, Sensitivity = %.2f)\n', epStopped, T, S);
                        stopped = true;
                        break;
                    end
                    for i = 1:size(X_train,1)
                        target_class = y_train(i) + 1;
                        tm_tmp = tm_tmp.update(X_train(i,:), target_class);
                    end
                    accCurveTmp(ep) = tm_tmp.evaluate(X_train, y_train);
                    lastEp = ep;
        
                    if mod(ep, showStep) == 0 || ep == 1 || ep == Epochs
                        x = 1:showStep:ep; if x(end) ~= ep, x = [x, ep]; end
                        set(app.hTrain,'XData',x,'YData',accCurveTmp(x));
                        drawnow limitrate;
                    end
                end
        
                % เลือก best จาก train acc
                if ~stopped
                    thisScore = tm_tmp.evaluate(X_train, y_train);
                    if thisScore > bestScore
                        bestScore     = thisScore;
                        bestModel     = tm_tmp;
                        bestT         = T;
                        bestS         = S;
                        bestAccCurve  = accCurveTmp(1:lastEp);
                        bestAccEpochs = 1:lastEp;
                    end
                end
            end
        
            function saveGraph()
                [file, path] = uiputfile('graph.png','Save Graph As');
                if isequal(file,0), return; end
                exportgraphics(app.UIAxes, fullfile(path, file));
                uialert(app.UIFigure,'Graph saved successfully!','Done','Icon','success');
            end
        
            function saveParams()
                % รวมค่าใช้งานร่วม
                baseRows = {
                    'Number of Clauses',            num2str(NumClauses);
                    'Number of States',             num2str(NumStates);
                    'Number of Features',           num2str(NumFeatures);
                    'Number of Classes',            num2str(NumClassesEff);
                    'Epochs (requested)',           num2str(Epochs);
                    'Epochs (completed)',           num2str(epStopped);
                    'Stopped early',                iif(wasStopped,'Yes','No');
                };
            
                if strcmp(app.Switch2.Value,"Sweep Mode")
                    % ป้องกันกรณีไม่มีตัวแปร order (เผื่อแก้ไขในอนาคต)
                    sweepOrder = '';
                    if exist('order','var'), sweepOrder = char(order); end
            
                    rows = {
                        'Mode',                      'Sweep Mode';
                        'Sweep order',               sweepOrder;
                        'Threshold Range Start',     num2str(ThresholdStart);
                        'Threshold Range End',       num2str(ThresholdEnd);
                        'Threshold Step',            num2str(ThresholdStep);
                        'Sensitivity Range Start',   num2str(SensitivityStart);
                        'Sensitivity Range End',     num2str(SensitivityEnd);
                        'Sensitivity Step',          num2str(SensitivityStep);
                    };
                    rows = [rows; baseRows; {
                        'Best Threshold (used)',     num2str(bestT);
                        'Best Sensitivity (used)',   num2str(bestS);
                        'Final Train Accuracy (%)',  num2str(final_train_acc,'%.4f');
                        'Final Test Accuracy (%)',   iif(~isnan(acc_test),num2str(acc_test,'%.4f'),'N/A');
                    }];
                else
                    rows = {
                        'Mode',                      'Fix Mode';
                        'Threshold (used)',          num2str(Threshold);
                        'Sensitivity (used)',        num2str(Sensitivity);
                    };
                    rows = [rows; baseRows; {
                        'Final Train Accuracy (%)',  num2str(final_train_acc,'%.4f');
                        'Final Test Accuracy (%)',   iif(~isnan(acc_test),num2str(acc_test,'%.4f'),'N/A');
                    }];
                end
            
                % ต่อท้ายข้อมูลไฟล์และเวลา
                rows = [rows; {
                    'Training File',                safeText(app.TrainingfileLabel.Text);
                    'Test File',                    safeText(app.TestfileLabel.Text);
                }];
            
                T = cell2table(rows,'VariableNames',{'Parameter','Value'});
                [file,path] = uiputfile('parameters.xlsx','Save Parameters As');
                if isequal(file,0), return; end
                writetable(T, fullfile(path,file));
                uialert(app.UIFigure,'Parameter saved to Excel successfully!','Done','Icon','success');
            end
        
            function val = getParam(leftVal,rightVal)
                if ~isempty(strtrim(rightVal)), val = rightVal; else, val = leftVal; end
            end
            function out = iif(cond,a,b)
                if cond, out=a; else, out=b; end
            end
            function s = safeText(x)
                if isempty(x), s=''; else, s=char(string(x)); end
            end
            function y0 = toZeroBased(yin)
                if isempty(yin), y0 = yin; return; end
                yin = yin(:);
                if all(mod(yin,1)==0) && min(yin) == 1
                    y0 = yin - 1;
                else
                    y0 = yin;
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            showTS(app, false);   % << ซ่อนเมื่อหยุด
            app.stopFlag = true;
            app.StopButton.Enable = 'off';
        
            % เปลี่ยนข้อความ + สีไฟเป็นส้ม
            app.TrainLabel.Text = 'Train';
            app.TrainLamp.Color = [1.0, 0.6, 0.0];   % ส้ม
            drawnow;
        
            % ตั้ง Timer → 3 วิ แล้วเปลี่ยนเป็นเทา
            if ~isempty(app.LampTimer) && isvalid(app.LampTimer)
                stop(app.LampTimer); delete(app.LampTimer);
            end
            app.LampTimer = timer( ...
                'StartDelay', 3, ...
                'ExecutionMode', 'singleShot', ...
                'TimerFcn', @(~,~) setLampGray(app));
            start(app.LampTimer);
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            % Reset ค่าอินพุตทั้งหมดและจัดสถานะตามโหมด
            resetInputsKeepFiles(app);

            if strcmp(app.Switch2.Value, 'Fix Mode')
                app.Threshold_2.Enable    = 'off';
                app.Sensitivity_2.Enable  = 'off';
                app.ThresholdStep.Enable  = 'off';
                app.SensitivityStep.Enable= 'off';
                app.ToLabel.Enable        = 'off';
                app.StepLabel.Enable      = 'off';
                app.ToLabel_2.Enable      = 'off';
                app.StepLabel_2.Enable    = 'off';
                app.SweepOrder.Enable     = 'off';
                app.SweepOrderLabel.Enable= 'off';
            else
                app.Threshold_2.Enable    = 'on';
                app.Sensitivity_2.Enable  = 'on';
                app.ThresholdStep.Enable  = 'on';
                app.SensitivityStep.Enable= 'on';
                app.ToLabel.Enable        = 'on';
                app.StepLabel.Enable      = 'on';
                app.ToLabel_2.Enable      = 'on';
                app.StepLabel_2.Enable    = 'on';
                app.SweepOrder.Enable     = 'on';
                app.SweepOrderLabel.Enable= 'on';
            end
        end
        
        % === ฟังก์ชัน Reset ช่องทั้งหมด ===
        function resetAllInputs(app)
            % ฝั่งซ้าย
            app.Threshold_1.Value        = '';
            app.Sensitivity_1.Value      = '';
            app.NumberofClauses_1.Value  = '';
            app.NumberofStates_1.Value   = '';
            app.NumberofFeatures_1.Value = '';
            app.NumberofClasses_1.Value  = '';
            app.Epochs_1.Value           = '';
        
            % ฝั่งขวา (ช่วง)
            app.Threshold_2.Value        = '';
            app.Sensitivity_2.Value      = '';
        
            % Step
            app.ThresholdStep.Value      = '';
            app.SensitivityStep.Value    = '';

            app.TrainingfileLabel.Text               = '';
            app.TestfileLabel.Text             = '';
        
            % ล้างกราฟ
            cla(app.UIAxes);
            title(app.UIAxes,''); xlabel(app.UIAxes,'Epoch'); ylabel(app.UIAxes,'Accuracy'); grid(app.UIAxes,'on');
        
            % ปิด Lamp
            app.TrainLamp.Color = [0.5 0.5 0.5];
        
            % ล้างโมเดล
            app.TrainedModel = [];
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: SelectTestFileButton
        function SelectTestFileButtonPushed(app, event)
            [file, path] = uigetfile({ ...
                '*.csv',  'CSV Files (*.csv)'; ...
                '*.xlsx', 'Excel Files (*.xlsx)'}, ...
                'Select Test File');
        
            if isequal(file,0)
                if isempty(app.XTest) || isempty(app.yTest)
                    app.TestfileLabel.Text = 'Test file has not been selected.';
                end
                return;
            end
        
            fullPath = fullfile(path,file);
        
            [data, errmsg] = readFlexibleFile(app, fullPath);
            if isempty(data)
                uialert(app.UIFigure, "Failed to read test file: " + errmsg, 'Error', 'Icon','error');
                return;
            end
            if size(data,2) < 2
                uialert(app.UIFigure,'The file must have at least 2 columns (features + label).','Invalid file format','Icon','error');
                return;
            end
        
            % แยก X/Y อัตโนมัติ (รองรับ label อยู่หัว/ท้าย)
            [Xt, yt] = splitXY_auto(app, data);
        
            app.TestfileLabel.Text = file;
            app.XTest = Xt;
            app.yTest = yt;
        
            disp("Test file loaded successfully: " + file);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Epochs')
            ylabel(app.UIAxes, 'Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.Threshold_2 = uieditfield(app.UIFigure, 'text');
            app.Sensitivity_2 = uieditfield(app.UIFigure, 'text');
            app.ThresholdTEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdTEditFieldLabel.Text = 'Threshold (T):';
            app.Threshold_1 = uieditfield(app.UIFigure, 'text');
            app.SensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity :';
            app.Sensitivity_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClausesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClausesEditFieldLabel.Text = 'Number of Clauses :';
            app.NumberofClauses_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofStatesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofStatesEditFieldLabel.Text = 'Number of States :';
            app.NumberofStates_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofFeaturesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFeaturesEditFieldLabel.Text = 'Number of Features :';
            app.NumberofFeatures_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClassesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClassesEditFieldLabel.Text = 'Number of Classes :';
            app.NumberofClasses_1 = uieditfield(app.UIFigure, 'text');
            app.EpochsEditFieldLabel = uilabel(app.UIFigure);
            app.EpochsEditFieldLabel.Text = 'Epochs :';
            app.Epochs_1 = uieditfield(app.UIFigure, 'text');
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.Items = {'Sweep Mode', 'Fix Mode'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Value = 'Sweep Mode';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TrainLamp = uilamp(app.UIFigure);
            app.SelectedFileLabel = uibutton(app.UIFigure, 'push');
            app.SelectedFileLabel.ButtonPushedFcn = createCallbackFcn(app, @SelectedFileLabelPushed, true);
            app.SelectedFileLabel.Text = 'Select Training File';
            app.SelectTestFileButton = uibutton(app.UIFigure, 'push');
            app.SelectTestFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTestFileButtonPushed, true);
            app.SelectTestFileButton.Text = 'Select Test File';
            app.TrainingfileLabel = uilabel(app.UIFigure);
            app.TrainingfileLabel.Text = 'ยังไม่ได้เลือก Training file';
            app.ThresholdStep = uieditfield(app.UIFigure, 'text');
            app.SensitivityStep = uieditfield(app.UIFigure, 'text');
            app.TestfileLabel = uilabel(app.UIFigure);
            app.TestfileLabel.Text = 'ยังไม่ได้เลือก Test file';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To';
            app.StepLabel = uilabel(app.UIFigure);
            app.StepLabel.Text = 'Step :';
            app.ToLabel_2 = uilabel(app.UIFigure);
            app.ToLabel_2.Text = 'To';
            app.StepLabel_2 = uilabel(app.UIFigure);
            app.StepLabel_2.Text = 'Step :';
            app.TrainLabel = uilabel(app.UIFigure);
            app.TrainLabel.Text = 'Train';
            app.ThresholdState = uilabel(app.UIFigure);
            app.ThresholdState.Text = '';
            app.SensitivityState = uilabel(app.UIFigure);
            app.SensitivityState.Text = '';
            app.ShowEpochsLabel = uilabel(app.UIFigure);
            app.ShowEpochsLabel.Text = 'Plot (epochs):';
            app.ShowEpochs = uieditfield(app.UIFigure, 'text');
            app.SweepOrder = uidropdown(app.UIFigure);
            app.SweepOrder.Items = {'T→S', 'S→T'};
            app.SweepOrder.Value = 'S→T';
            app.SweepOrderLabel = uilabel(app.UIFigure);
            app.SweepOrderLabel.Text = 'SweepOrder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TsetlinGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end