classdef TsetlinGUI_With_Call_Model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectedFileLabel               matlab.ui.control.Button
        TrainingLamp                    matlab.ui.control.Lamp
        TrainingLampLabel               matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        Switch2                         matlab.ui.control.Switch
        ControlPanelLabel               matlab.ui.control.Label
        Epochs_1                        matlab.ui.control.EditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        NumberofClasses_1               matlab.ui.control.EditField
        NumberofClassesEditFieldLabel   matlab.ui.control.Label
        NumberofFeatures_1              matlab.ui.control.EditField
        NumberofFeaturesEditFieldLabel  matlab.ui.control.Label
        NumberofStates_1                matlab.ui.control.EditField
        NumberofStatesEditFieldLabel    matlab.ui.control.Label
        NumberofClauses_1               matlab.ui.control.EditField
        NumberofClausesEditFieldLabel   matlab.ui.control.Label
        Sensitivity_1                   matlab.ui.control.EditField
        SensitivityEditFieldLabel       matlab.ui.control.Label
        Threshold_1                     matlab.ui.control.EditField
        ThresholdTEditFieldLabel        matlab.ui.control.Label
        Epochs_2                        matlab.ui.control.EditField
        NumberofClasses_2               matlab.ui.control.EditField
        NumberofFeatures_2              matlab.ui.control.EditField
        NumberofStates_2                matlab.ui.control.EditField
        NumberofClauses_2               matlab.ui.control.EditField
        Sensitivity_2                   matlab.ui.control.EditField
        Threshold_2                     matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = public)
        SelectedFile
        XTrain
        yTrain
        XVal
        yVal
        TrainedModel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TrainButton.Enable = 'off';
        end

        % Button pushed function: SelectedFileLabel
        function SelectedFileLabelPushed(app, event)
        [file, path] = uigetfile({ ...
            '*.csv', 'CSV Files (*.csv)'; ...
            '*.xlsx', 'Excel Files (*.xlsx)'}, ...
            'Select a file');
    
        if isequal(file, 0)
            uialert(app.UIFigure, 'คุณยังไม่ได้เลือกไฟล์', 'ข้อผิดพลาด', 'Icon', 'warning');
        else
            % ✅ เก็บ path จริงไว้เพื่อให้ Train ใช้ได้
            app.SelectedFile = fullfile(path, file);
    
            % ✅ แสดงชื่อไฟล์ใน label
            app.SelectedFileLabel.Text = file;
    
            % ✅ เปิดปุ่ม Train หลังเลือกไฟล์
            app.TrainButton.Enable = 'on';
            % === อ่านข้อมูลจากไฟล์ ===
            if endsWith(file, '.csv')
                data = readmatrix(app.SelectedFile);
            elseif endsWith(file, '.xlsx')
                data = readmatrix(app.SelectedFile);  % หรือ readtable ก็ได้
            else
                uialert(app.UIFigure, 'ไม่รองรับไฟล์นี้', 'Error', 'Icon', 'error');
                return;
            end
            
            % === Assign ข้อมูล ===
            app.yTrain = data(:, 1);           % column แรกเป็น label
            app.XTrain = data(:, 2:end);       % columns ที่เหลือเป็น features
            
        end
    end

        % ✅ เพิ่มฟังก์ชันใหม่ด้านล่างนี้เลย
        function saveParamsToFile(app, ~)
        % สร้างตารางค่าพารามิเตอร์
        params = {
            'Threshold', app.Threshold_1.Value, app.Threshold_2.Value;
            'Sensitivity', app.Sensitivity_1.Value, app.Sensitivity_2.Value;
            'Number of Clauses', app.NumberofClauses_1.Value, app.NumberofClauses_2.Value;
            'Number of States', app.NumberofStates_1.Value, app.NumberofStates_2.Value;
            'Number of Features', app.NumberofFeatures_1.Value, app.NumberofFeatures_2.Value;
            'Number of Classes', app.NumberofClasses_1.Value, app.NumberofClasses_2.Value;
            'Epochs', app.Epochs_1.Value, app.Epochs_2.Value;
        };
    
        % แปลงเป็นตาราง
        T = cell2table(params, ...
            'VariableNames', {'Parameter', 'Value_1', 'Value_2'});
    
        % เลือก path ไฟล์
        [file, path] = uiputfile('parameters.xlsx', 'Save Parameters As');
        if isequal(file, 0)
            return;
        end
        fullPath = fullfile(path, file);
    
        % ✅ เขียนลงไฟล์ .xlsx
        writetable(T, fullPath);
    
        % Popup สำเร็จ
        uialert(app.UIFigure, 'Parameter saved to Excel successfully!', ...
            'Done', 'Icon', 'success');
        end
        function saveGraphToImage(~, axesHandle, app)
                disp([epoch, acc_train(epoch), acc_val(epoch)]);  % DEBUG
        
                [file, path] = uiputfile({'*.png'; '*.jpg'}, 'Save Graph As', 'graph.png');
                if isequal(file, 0)
                    return;
                end
        
                fullPath = fullfile(path, file);
        
                try
                    exportgraphics(axesHandle, fullPath);
                    uialert(app.UIFigure, 'Graph saved successfully!', 'Done', 'Icon', 'success');
                catch ME
                    uialert(app.UIFigure, "Error saving graph: " + ME.message, 'Error', 'Icon', 'error');
                end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
        app.Threshold_1.Value = '';
        app.Threshold_2.Value = '';
        app.Sensitivity_1.Value = '';
        app.Sensitivity_2.Value = '';
        app.NumberofClauses_1.Value = '';
        app.NumberofClauses_2.Value = '';
        app.NumberofStates_1.Value = '';
        app.NumberofStates_2.Value = '';
        app.NumberofFeatures_1.Value = '';
        app.NumberofFeatures_2.Value = '';
        app.NumberofClasses_1.Value = '';
        app.NumberofClasses_2.Value = '';
        app.Epochs_1.Value = '';
        app.Epochs_2.Value = '';
        app.SelectedFileLabel.Text = 'Select File';
    
        % รีเซ็ตกราฟ
        cla(app.UIAxes);
        title(app.UIAxes, '');
        xlabel(app.UIAxes, 'Epochs');
        ylabel(app.UIAxes, 'Accuracy');
    
        % ดับไฟ
        app.TrainingLamp.Color = [0.5 0.5 0.5];
    
        % หยุดการเทรน
        app.IsTraining = false;
    
        % ล้างการเลือกไฟล
        app.SelectedFile = '';
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            Epochs = str2double(app.Epochs_1.Value);
        
            % === เตรียมข้อมูล ===
            X_train = app.XTrain;
            y_train = app.yTrain;
            X_val = app.XVal;
            y_val = app.yVal;
        
            % ถ้า validation ว่าง → ใช้ train แทน
            if isempty(app.XVal) || isempty(app.yVal)
                X_val = app.XTrain;
                y_val = app.yTrain;
            else
                X_val = app.XVal;
                y_val = app.yVal;
            end
        
            num_classes = str2double(app.NumberofClasses_1.Value);
            num_clauses = str2double(app.NumberofClauses_1.Value);
        
            % ตรวจ clauses ต่อ class
            if floor(num_clauses / num_classes) <= 0
                uialert(app.UIFigure, 'จำนวน Clauses ต้องมากกว่าจำนวน Classes หลายเท่า (อย่างน้อย 10x)', 'Error', 'Icon', 'error');
                return;
            end
        
            % === กรอง NaN ใน y_train ===
            valid_idx = ~isnan(y_train);
            X_train = X_train(valid_idx, :);
            y_train = y_train(valid_idx);
        
            % === สร้างหรือดึง Model ===
            if isempty(app.TrainedModel)
                num_features = size(X_train, 2);
                num_states = 100;
                s = 3.9;
                threshold = 15;
                boost_tp = 1;
        
                tm = TsetlinMachine(num_classes, num_clauses, num_features, num_states, s, threshold, boost_tp);
            else
                tm = app.TrainedModel;
            end
        
            acc_train = zeros(1, Epochs);
            acc_val = zeros(1, Epochs);
        
            % === เตรียมกราฟ ===
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            xlabel(app.UIAxes, 'Epoch');
            ylabel(app.UIAxes, 'Accuracy');
            title(app.UIAxes, 'Training and Validation Accuracy');
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [1 Epochs]);
            ylim(app.UIAxes, [0 1]);
        
            hTrain = plot(app.UIAxes, nan, nan, '-o', 'DisplayName', 'Train Accuracy');
            hVal   = plot(app.UIAxes, nan, nan, '-s', 'DisplayName', 'Validation Accuracy');
            legend(app.UIAxes, 'show');
        
            % === เปิด Lamp ===
            app.TrainingLamp.Color = [1, 1, 0];
        
            % === Train ===
            for epoch = 1:Epochs
                for i = 1:size(X_train, 1)
                    target_class = y_train(i) + 1;
        
                    % ตรวจสอบค่า
                    if isnan(target_class) || target_class < 1 || target_class > num_classes
                        error("พบ target_class ไม่ถูกต้อง: %.2f (index %d)", target_class, i);
                    end
        
                    tm = tm.update(X_train(i, :), target_class);
                end
        
                acc_train(epoch) = tm.evaluate(X_train, y_train);
                acc_val(epoch) = tm.evaluate(X_val, y_val);
        
                set(hTrain, 'XData', 1:epoch, 'YData', acc_train(1:epoch));
                set(hVal, 'XData', 1:epoch, 'YData', acc_val(1:epoch));
                drawnow;
            end
        
            % === ปิด Lamp ===
            app.TrainingLamp.Color = [0, 1, 0];
        
            % === เก็บกลับ ===
            app.TrainedModel = tm;
        
            % === สรุป accuracy สุดท้าย ===
            final_train_acc = acc_train(end) * 100;
            final_val_acc = acc_val(end) * 100;
        
            % === แสดง Popup ===
            msg = sprintf(['Training Completed!\n\n' ...
                'Final Train Accuracy: %.2f%%\nFinal Val Accuracy: %.2f%%'], ...
                final_train_acc, final_val_acc);
        
            uialert(app.UIFigure, msg, 'Training Result', 'Icon', 'success');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        % ดับไฟ (เปลี่ยนสี Lamp เป็นเทา)
        app.TrainingLamp.Color = [0.5 0.5 0.5]; % สีเทา
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
        mode = app.Switch2.Value;
    
        % List ชื่อ component ด้านขวา
        rightInputs = [
            app.Threshold_2, app.Sensitivity_2, app.NumberofClauses_2, ...
            app.NumberofStates_2, app.NumberofFeatures_2, ...
            app.NumberofClasses_2, app.Epochs_2
        ];
    
        if strcmp(mode, 'Manual Mode')
            % ซ่อนช่องด้านขวา
            for i = 1:numel(rightInputs)
                rightInputs(i).Visible = 'off';
            end
        else
            % แสดงช่องด้านขวา
            for i = 1:numel(rightInputs)
                rightInputs(i).Visible = 'on';
            end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Epochs')
            ylabel(app.UIAxes, 'Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.Threshold_2 = uieditfield(app.UIFigure, 'text');
            app.Sensitivity_2 = uieditfield(app.UIFigure, 'text');
            app.NumberofClauses_2 = uieditfield(app.UIFigure, 'text');
            app.NumberofStates_2 = uieditfield(app.UIFigure, 'text');
            app.NumberofFeatures_2 = uieditfield(app.UIFigure, 'text');
            app.NumberofClasses_2 = uieditfield(app.UIFigure, 'text');
            app.Epochs_2 = uieditfield(app.UIFigure, 'text');
            app.ThresholdTEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdTEditFieldLabel.Text = 'Threshold (T):';
            app.Threshold_1 = uieditfield(app.UIFigure, 'text');
            app.SensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity :';
            app.Sensitivity_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClausesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClausesEditFieldLabel.Text = 'Number of Clauses :';
            app.NumberofClauses_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofStatesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofStatesEditFieldLabel.Text = 'Number of States :';
            app.NumberofStates_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofFeaturesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFeaturesEditFieldLabel.Text = 'Number of Features :';
            app.NumberofFeatures_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClassesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClassesEditFieldLabel.Text = 'Number of Classes :';
            app.NumberofClasses_1 = uieditfield(app.UIFigure, 'text');
            app.EpochsEditFieldLabel = uilabel(app.UIFigure);
            app.EpochsEditFieldLabel.Text = 'Epochs :';
            app.Epochs_1 = uieditfield(app.UIFigure, 'text');
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.Items = {'Sweep Mode', 'Manual Mode'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Value = 'Sweep Mode';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TrainingLampLabel = uilabel(app.UIFigure);
            app.TrainingLampLabel.Text = 'Training';
            app.TrainingLamp = uilamp(app.UIFigure);
            app.SelectedFileLabel = uibutton(app.UIFigure, 'push');
            app.SelectedFileLabel.ButtonPushedFcn = createCallbackFcn(app, @SelectedFileLabelPushed, true);
            app.SelectedFileLabel.Text = 'Select File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TsetlinGUI_With_Call_Model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end