classdef TsetlinGUI_With_Call_Model_2_Selectfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SweepOrder                      matlab.ui.control.DropDown
        SweepOrderLabel                 matlab.ui.control.Label
        ShowEpochs                      matlab.ui.control.EditField
        ShowEpochsLabel                 matlab.ui.control.Label
        SensitivityState                matlab.ui.control.Label
        ThresholdState                  matlab.ui.control.Label
        TrainLabel                      matlab.ui.control.Label
        StepLabel_2                     matlab.ui.control.Label
        ToLabel_2                       matlab.ui.control.Label
        StepLabel                       matlab.ui.control.Label
        ToLabel                         matlab.ui.control.Label
        TestfileLabel                   matlab.ui.control.Label
        SensitivityStep                 matlab.ui.control.EditField
        ThresholdStep                   matlab.ui.control.EditField
        TrainingfileLabel               matlab.ui.control.Label
        SelectTestFileButton            matlab.ui.control.Button
        SelectedFileLabel               matlab.ui.control.Button
        TrainLamp                       matlab.ui.control.Lamp
        ResetButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        Switch2                         matlab.ui.control.Switch
        Epochs_1                        matlab.ui.control.EditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        NumberofClasses_1               matlab.ui.control.EditField
        NumberofClassesEditFieldLabel   matlab.ui.control.Label
        NumberofFeatures_1              matlab.ui.control.EditField
        NumberofFeaturesEditFieldLabel  matlab.ui.control.Label
        NumberofStates_1                matlab.ui.control.EditField
        NumberofStatesEditFieldLabel    matlab.ui.control.Label
        NumberofClauses_1               matlab.ui.control.EditField
        NumberofClausesEditFieldLabel   matlab.ui.control.Label
        Sensitivity_1                   matlab.ui.control.EditField
        SensitivityEditFieldLabel       matlab.ui.control.Label
        Threshold_1                     matlab.ui.control.EditField
        ThresholdTEditFieldLabel        matlab.ui.control.Label
        Sensitivity_2                   matlab.ui.control.EditField
        Threshold_2                     matlab.ui.control.EditField
        ControlPanelLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

        properties (Access = public)
            SelectedFile
            XTrain
            yTrain
            XVal
            yVal
            TrainedModel
            XTest
            yTest
        end
        
        properties (Access = private)
            IsTraining = false
            stopFlag = false % ใช้สำหรับกดหยุด
            hTrain     matlab.graphics.chart.primitive.Line
            LampTimer  % ใช้หน่วง 3 วิ ก่อนเปลี่ยนเป็นสีเทา
        end
    
        % ===== Helpers =====
        methods (Access = private)
            function setLampGray(app)
                if ~app.IsTraining
                    app.TrainLamp.Color = [0.5 0.5 0.5]; % เทา
                end
            end
    
            function setControlsEnabled(app, tf)
                % tf = true -> enable controls (respect current mode)
                % tf = false -> disable everything relevant during training
                state = tern(app, tf, 'on', 'off');
    
                % ------- กลุ่มปุ่ม/ไฟล์/ปุ่มรีเซ็ต -------
                app.Switch2.Enable              = state;
                app.SelectedFileLabel.Enable    = state;   % Select Training File
                app.SelectTestFileButton.Enable = state;   % Select Test File
                app.ResetButton.Enable          = state;
            
                % ------- ฝั่งซ้าย -------
                app.Threshold_1.Enable          = state;
                app.Sensitivity_1.Enable        = state;
                app.NumberofClauses_1.Enable    = state;
                app.NumberofStates_1.Enable     = state;
                app.NumberofFeatures_1.Enable   = state;
                app.NumberofClasses_1.Enable    = state;
                app.Epochs_1.Enable             = state;
            
                % ------- ฝั่งขวา (ขึ้นกับโหมด) -------
                if ~tf
                    rstate = 'off';  % ระหว่างเทรน ปิดทั้งหมด
                else
                    if strcmp(app.Switch2.Value,'Sweep Mode')
                        rstate = 'on';   % หลังเทรน + Sweep -> เปิดได้
                    else
                        rstate = 'off';  % หลังเทรน + Fix -> คงปิดไว้
                    end
                end
                app.Threshold_2.Enable          = rstate;
                app.Sensitivity_2.Enable        = rstate;
                app.ThresholdStep.Enable        = rstate;
                app.SensitivityStep.Enable      = rstate;
                app.ToLabel.Enable              = rstate;
                app.StepLabel.Enable            = rstate;
                app.ToLabel_2.Enable            = rstate;
                app.StepLabel_2.Enable          = rstate;
    
                % ------- Sweep order (ขึ้นกับโหมด + tf) -------
                if tf && strcmp(app.Switch2.Value,'Sweep Mode')
                    app.SweepOrder.Enable = 'on';
                    app.SweepOrderLabel.Enable = 'on';
                else
                    app.SweepOrder.Enable = 'off';
                    app.SweepOrderLabel.Enable = 'on';
                end
            end
    
            function out = tern(~,cond,a,b)
                if cond, out=a; else, out=b; end
            end
    
            function showTS(app, tf)
                vis = tern(app, tf, 'on', 'off');
                app.ThresholdState.Visible   = vis;
                app.SensitivityState.Visible = vis;
            end
            
            function setStateTS(app, T, S)
                if nargin>=2, app.ThresholdState.Text  = sprintf('Threshold : %.2f', T); end
                if nargin>=3, app.SensitivityState.Text = sprintf('Sensitivity : %.2f', S); end
            end

            function restoreUI(app)
                % ซ่อนสถานะ T,S
                showTS(app, false);
            
                % ปรับสถานะทั่วไป
                app.IsTraining          = false;
                app.TrainLabel.Text     = 'Train';
                app.TrainLamp.Color     = [0.5 0.5 0.5];
                app.StopButton.Enable   = 'off';
                app.TrainButton.Enable  = 'on';
            
                % เปิดสวิตช์/ปุ่มเลือกไฟล์/รีเซ็ต
                app.Switch2.Enable              = 'on';
                app.SelectedFileLabel.Enable    = 'on';
                app.SelectTestFileButton.Enable = 'on';
                app.ResetButton.Enable          = 'on';
            
                % เปิดช่องกรอกตามโหมด
                setControlsEnabled(app, true);
            
                drawnow;
            end

            function resetInputsKeepFiles(app)
                % เคลียร์ช่องตัวเลข/ช่วง/สเต็ปเท่านั้น
                app.Threshold_1.Value        = '';
                app.Sensitivity_1.Value      = '';
                app.NumberofClauses_1.Value  = '';
                app.NumberofStates_1.Value   = '';
                app.NumberofFeatures_1.Value = '';
                app.NumberofClasses_1.Value  = '';
                app.Epochs_1.Value           = '';
            
                app.Threshold_2.Value        = '';
                app.Sensitivity_2.Value      = '';
                app.ThresholdStep.Value      = '';
                app.SensitivityStep.Value    = '';
            
                % ไม่แตะต้อง: app.TrainingfileLabel, app.TestfileLabel, app.SelectedFile, X/Y train/val/test
                % จะไม่ไปล้างไฟล์ที่เลือกไว้
            end

        end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TrainButton.Enable = 'off';
            app.StopButton.Enable  = 'off';
            app.ThresholdState.Visible  = 'off';
            app.SensitivityState.Visible = 'off';
            if isempty(app.ShowEpochs.Value), app.ShowEpochs.Value = '10'; end
        
            % ข้อความเริ่มต้นข้างปุ่มเลือกไฟล์
            app.TrainingfileLabel.Text = 'ยังไม่ได้เลือก Training file';
            app.TestfileLabel.Text     = 'ยังไม่ได้เลือก Test file';
        
            % ตั้งสถานะ enable เริ่มต้นตามโหมด
            setControlsEnabled(app, true);
        end

        % Button pushed function: SelectedFileLabel
        function SelectedFileLabelPushed(app, event)
        [file, path] = uigetfile({ ...
            '*.csv', 'CSV Files (*.csv)'; ...
            '*.xlsx', 'Excel Files (*.xlsx)'}, ...
            'Select a file');
        
        if isequal(file, 0)
            uialert(app.UIFigure, 'คุณยังไม่ได้เลือกไฟล์', 'ข้อผิดพลาด', 'Icon', 'warning');
        else
            app.SelectedFile = fullfile(path, file);
        
            % ✅ แสดงชื่อไฟล์ที่ Label (แทนข้อความในปุ่ม)
            app.TrainingfileLabel.Text = file;
        
            app.TrainButton.Enable = 'on';
        
            if endsWith(file, '.csv')
                data = readmatrix(app.SelectedFile);
            elseif endsWith(file, '.xlsx')
                data = readmatrix(app.SelectedFile);
            else
                uialert(app.UIFigure, 'ไม่รองรับไฟล์นี้', 'Error', 'Icon', 'error');
                return;
            end

            % อัพเดตข้อความบนปุ่ม (optional)
            app.TrainingfileLabel.Text = file;
            disp("Training file loaded successfully.");
        
            app.yTrain = data(:, 1);
            app.XTrain = data(:, 2:end);
        end

    end

        % ✅ เพิ่มฟังก์ชันใหม่ด้านล่างนี้เลย
        function saveParamsToFile(app, ~)
        % สร้างตารางค่าพารามิเตอร์
        params = {
            'Threshold', app.EditField.Value, app.EditField.Value;
            'Sensitivity', app.EditField.Value, app.EditField.Value;
            'Number of Clauses', app.EditField2.Value;
            'Number of States', app.EditField3.Value;
            'Number of Features', app.EditField4.Value;
            'Number of Classes', app.EditField5.Value;
            'Epochs', app.EditField.Value;
        };
    
        % แปลงเป็นตาราง
        T = cell2table(params, ...
            'VariableNames', {'Parameter', 'Value_1', 'Value_2'});
    
        % เลือก path ไฟล์
        [file, path] = uiputfile('parameters.xlsx', 'Save Parameters As');
        if isequal(file, 0)
            return;
        end
        fullPath = fullfile(path, file);
    
        % ✅ เขียนลงไฟล์ .xlsx
        writetable(T, fullPath);
    
        % Popup สำเร็จ
        uialert(app.UIFigure, 'Parameter saved to Excel successfully!', ...
            'Done', 'Icon', 'success');
        end
        function saveGraphToImage(~, axesHandle, app)
                disp([epoch, acc_train(epoch), acc_val(epoch)]);  % DEBUG
        
                [file, path] = uiputfile({'*.png'; '*.jpg'}, 'Save Graph As', 'graph.png');
                if isequal(file, 0)
                    return;
                end
        
                fullPath = fullfile(path, file);
        
                try
                    exportgraphics(axesHandle, fullPath);
                    uialert(app.UIFigure, 'Graph saved successfully!', 'Done', 'Icon', 'success');
                catch ME
                    uialert(app.UIFigure, "Error saving graph: " + ME.message, 'Error', 'Icon', 'error');
                end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % ==== ยกเลิกการเทรนที่อาจกำลังรันอยู่ ====
            app.stopFlag   = true;
            app.IsTraining = false;
            drawnow;
        
            % ==== เคลียร์ค่าพารามิเตอร์ฝั่งซ้าย ====
            app.Threshold_1.Value        = '';
            app.Sensitivity_1.Value      = '';
            app.NumberofClauses_1.Value  = '';
            app.NumberofStates_1.Value   = '';
            app.NumberofFeatures_1.Value = '';
            app.NumberofClasses_1.Value  = '';
            app.Epochs_1.Value           = '';
        
            % ==== เคลียร์ค่าพารามิเตอร์ฝั่งขวา (ช่วง/สเต็ป) ====
            app.Threshold_2.Value        = '';
            app.Sensitivity_2.Value      = '';
            app.ThresholdStep.Value      = '';
            app.SensitivityStep.Value    = '';
        
            % ==== เคลียร์ชื่อไฟล์ที่แสดง ====
            app.TrainingfileLabel.Text = 'ยังไม่ได้เลือก Training file';
            app.TestfileLabel.Text     = 'ยังไม่ได้เลือก Test file';
        
            % ==== ล้างข้อมูล Train/Val/Test และไฟล์ที่เลือก ====
            app.SelectedFile = '';
            app.XTrain = []; app.yTrain = [];
            app.XVal   = []; app.yVal   = [];
            app.XTest  = []; app.yTest  = [];
        
            % ==== ล้างโมเดลที่เทรนไว้ ====
            app.TrainedModel = [];
        
            % ==== รีเซ็ตกราฟ ====
            cla(app.UIAxes);
            hold(app.UIAxes, 'off');
            grid(app.UIAxes, 'on');
            title(app.UIAxes, '');
            xlabel(app.UIAxes, 'Epoch');
            ylabel(app.UIAxes, 'Accuracy');
            drawnow;
        
            % ==== ดับไฟสถานะ ====
            app.TrainLamp.Color = [0.5 0.5 0.5];
        
            % ==== ปิดปุ่ม Train จนกว่าจะเลือกไฟล์ใหม่ ====
            app.TrainButton.Enable = 'off';
            app.StopButton.Enable  = 'off';

            % เปิด/ปิดช่องตามโหมด
            setControlsEnabled(app, true);

            % รีเซ็ตข้อความสถานะ
            showTS(app, false);
            app.ThresholdState.Text   = 'Threshold :';
            app.SensitivityState.Text = 'Sensitivity :';
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            % ต้องมี Training file เสมอ
            if isempty(app.SelectedFile) || isempty(app.XTrain) || isempty(app.yTrain)
                app.TrainingfileLabel.Text = 'ยังไม่ได้เลือก Training file';
                uialert(app.UIFigure,'ยังไม่ได้เลือก Training file','Missing file','Icon','warning');
                return;
            end
        
            % Test file ไม่บังคับ ถ้าไม่มีให้โชว์ข้อความที่ Label
            if isempty(app.XTest) || isempty(app.yTest)
                app.TestfileLabel.Text = 'ยังไม่ได้เลือก Test file';
            end
        
            % === เตรียมข้อมูลเริ่มต้น ===
            X_train = app.XTrain;   y_train = app.yTrain;
            X_val   = app.XVal;     y_val   = app.yVal;
        
            % === ตรวจค่าจาก UI ที่จำเป็น ===
            requiredLeft = { ...
                app.Threshold_1.Value, app.Sensitivity_1.Value, ...
                app.NumberofClauses_1.Value, app.NumberofStates_1.Value, ...
                app.NumberofFeatures_1.Value, app.NumberofClasses_1.Value, ...
                app.Epochs_1.Value };
        
            if strcmp(app.Switch2.Value, "Sweep Mode")
                requiredRight = { ...
                    app.Threshold_2.Value, app.Sensitivity_2.Value, ...
                    app.ThresholdStep.Value, app.SensitivityStep.Value };
                if any(cellfun(@isempty, requiredLeft)) || any(cellfun(@isempty, requiredRight))
                    uialert(app.UIFigure,'กรุณากรอกค่าพารามิเตอร์ให้ครบทั้งสองฝั่งก่อน Train','Error','Icon','error');
                    return;
                end
            else
                if any(cellfun(@isempty, requiredLeft))
                    uialert(app.UIFigure,'กรุณากรอกค่าพารามิเตอร์ให้ครบก่อน Train','Error','Icon','error');
                    return;
                end
            end
        
            % === อ่านพารามิเตอร์ตามโหมด ===
            if strcmp(app.Switch2.Value, "Sweep Mode")
                ThresholdStart   = str2double(app.Threshold_1.Value);
                ThresholdEnd     = str2double(app.Threshold_2.Value);
                ThresholdStep    = str2double(app.ThresholdStep.Value);
        
                SensitivityStart = str2double(app.Sensitivity_1.Value);
                SensitivityEnd   = str2double(app.Sensitivity_2.Value);
                SensitivityStep  = str2double(app.SensitivityStep.Value);
        
                if any(isnan([ThresholdStart,ThresholdEnd,ThresholdStep,SensitivityStart,SensitivityEnd,SensitivityStep])) ...
                   || ThresholdStep <= 0 || SensitivityStep <= 0
                    uialert(app.UIFigure,'ค่าของช่วง/สเต็ปไม่ถูกต้อง','Error','Icon','error');
                    return;
                end
                if ThresholdStart > ThresholdEnd || SensitivityStart > SensitivityEnd
                    uialert(app.UIFigure,'ค่า Start ต้องไม่มากกว่า End','Error','Icon','error');
                    return;
                end
        
                TRange = ThresholdStart:ThresholdStep:ThresholdEnd;
                SRange = SensitivityStart:SensitivityStep:SensitivityEnd;
        
                NumClauses  = str2double(app.NumberofClauses_1.Value);
                NumStates   = str2double(app.NumberofStates_1.Value);
                NumFeatures = str2double(app.NumberofFeatures_1.Value);
                NumClasses  = str2double(app.NumberofClasses_1.Value);
                Epochs      = str2double(app.Epochs_1.Value);
            else
                % Fix Mode
                Threshold   = str2double(getParam(app.Threshold_1.Value, app.Threshold_2.Value));
                Sensitivity = str2double(getParam(app.Sensitivity_1.Value, app.Sensitivity_2.Value));
                NumClauses  = str2double(app.NumberofClauses_1.Value);
                NumStates   = str2double(app.NumberofStates_1.Value);
                NumFeatures = str2double(app.NumberofFeatures_1.Value);
                NumClasses  = str2double(app.NumberofClasses_1.Value);
                Epochs      = str2double(app.Epochs_1.Value);
            end
        
            % === ตรวจความสมเหตุสมผล ===
            if isnan(Epochs) || Epochs < 1 || mod(Epochs,1) ~= 0
                uialert(app.UIFigure,'ค่า Epochs ต้องเป็นจำนวนเต็มบวกมากกว่า 0','Error','Icon','error');
                return;
            end
            if isnan(NumClauses) || isnan(NumClasses) || floor(NumClauses/NumClasses) <= 0
                uialert(app.UIFigure,'จำนวน Clauses ต้องมากกว่าจำนวน Classes หลายเท่า (อย่างน้อย 10x)','Error','Icon','error');
                return;
            end
        
            % === อ่านค่า ShowEpochs ===
            if ~isprop(app,'ShowEpochs') || isempty(strtrim(string(app.ShowEpochs.Value)))
                uialert(app.UIFigure, 'กรุณากรอกค่า "Show Epochs"', 'Invalid Show Epochs', 'Icon','error');
                return;
            end
            showStep = str2double(app.ShowEpochs.Value);
            if isnan(showStep) || mod(showStep,1)~=0 || showStep < 1 || showStep > Epochs
                uialert(app.UIFigure, sprintf('ค่า "Show Epochs" ต้องเป็นจำนวนเต็มตั้งแต่ 1 ถึง %d', Epochs), ...
                    'Invalid Show Epochs','Icon','error');
                return;
            end
            showStep = floor(showStep);
        
            % === กรอง NaN ใน y_train ===
            valid_idx = ~isnan(y_train);
            X_train = X_train(valid_idx,:);
            y_train = y_train(valid_idx);
        
            % === ถ้าไม่มี Validation → ทำ hold-out 20% แบบ stratified ===
            if isempty(X_val) || isempty(y_val)
                X_all = X_train; y_all = y_train;
                classes = unique(y_all(~isnan(y_all)));
                idx_train = []; idx_val = [];
                val_ratio = 0.20;
        
                rng('shuffle');
                for c = classes'
                    idx_c = find(y_all == c);
                    idx_c = idx_c(randperm(numel(idx_c)));
                    nval  = max(1, round(val_ratio * numel(idx_c)));
                    idx_val   = [idx_val;   idx_c(1:nval)];
                    idx_train = [idx_train; idx_c(nval+1:end)];
                end
                idx_train = unique(idx_train,'stable');
                if isempty(idx_train)
                    uialert(app.UIFigure,'ไม่สามารถแบ่ง hold-out ได้: ตัวอย่างต่อคลาสน้อยเกินไป','Error','Icon','error');
                    return;
                end
        
                X_val   = X_all(idx_val, :);   y_val   = y_all(idx_val);
                X_train = X_all(idx_train, :); y_train = y_all(idx_train);
            end
        
            % === เตรียมกราฟ (สร้างเส้นครั้งเดียว แล้วอัปเดต X/YData) ===
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            xlabel(app.UIAxes,'Epoch'); ylabel(app.UIAxes,'Accuracy');
            title(app.UIAxes,'Training Accuracy'); grid(app.UIAxes,'on');
            xlim(app.UIAxes,[1 Epochs]); ylim(app.UIAxes,[0 1]);
            app.hTrain = plot(app.UIAxes, NaN, NaN, '-');  % <- เส้นเดียว
            drawnow;
        
            % === เปิดไฟสถานะ + ปรับปุ่ม ===
            app.TrainLamp.Color = [1 1 0];        % เหลือง = กำลังเทรน
            app.TrainLabel.Text = 'Training';
            app.StopButton.Enable  = 'on';
            app.TrainButton.Enable = 'off';
            app.IsTraining         = true;
        
            % ล็อคการเปลี่ยนโหมด/เลือกไฟล์/รีเซ็ต + ฟิลด์ต่าง ๆ
            app.Switch2.Enable = 'off';
            app.SelectedFileLabel.Enable    = 'off';
            app.SelectTestFileButton.Enable = 'off';
            app.ResetButton.Enable          = 'off';
            setControlsEnabled(app, false);
        
            % แสดงค่า T,S เฉพาะตอนเทรน
            showTS(app, true);
            if strcmp(app.Switch2.Value,'Sweep Mode')
                app.ThresholdState.Text   = 'T = -';
                app.SensitivityState.Text = 'S = -';
            else
                app.ThresholdState.Text   = sprintf('T = %.2f', Threshold);
                app.SensitivityState.Text = sprintf('S = %.2f', Sensitivity);
            end
            drawnow limitrate;
        
            % === Train (ครอบด้วย try/catch เพื่อคืน UI เสมอ) ===
            try
                app.stopFlag = false;
                stopNotice = '';
                wasStopped = false;
        
                accTrainSeries = nan(1, Epochs);
                epStopped = 0;
        
                bestValAcc = -inf; bestModel = []; bestT = NaN; bestS = NaN;
        
                if strcmp(app.Switch2.Value, "Sweep Mode")
                    outerBreak = false;
        
                    % อ่านลำดับจาก DropDown
                    order = 'T→S';
                    if isprop(app,'SweepOrder') && ~isempty(app.SweepOrder.Value)
                        order = app.SweepOrder.Value;
                    end
        
                    switch order
                        case 'T→S'
                            for T = TRange
                                if app.stopFlag, outerBreak = true; break; end
                                for S = SRange
                                    if trainOnePair(T,S), outerBreak = true; break; end
                                end
                                if outerBreak, break; end
                            end
                        case 'S→T'
                            for S = SRange
                                if app.stopFlag, outerBreak = true; break; end
                                for T = TRange
                                    if trainOnePair(T,S), outerBreak = true; break; end
                                end
                                if outerBreak, break; end
                            end
                    end
        
                    % เลือกโมเดลที่จะรายงาน
                    if ~isempty(bestModel)
                        tm = bestModel;  Threshold = bestT; Sensitivity = bestS; %#ok<NASGU>
                    else
                        restoreUI();   % <<< ปลดล็อกก่อนออก
                        uialert(app.UIFigure,'Training ถูกยกเลิกก่อนเริ่มเทรน','Info','Icon','warning');
                        return;
                    end
        
                else
                    % Fix Mode
                    tm = TsetlinMachine(NumClasses, NumClauses, NumFeatures, NumStates, Sensitivity, Threshold, 1); %#ok<NASGU>
                    for ep = 1:Epochs
                        if app.stopFlag
                            wasStopped = true;
                            epStopped  = ep;
                            stopNotice = sprintf('⚠ Training stopped at Epoch %d (Threshold = %.2f, Sensitivity = %.2f)\n', ...
                                                 epStopped, Threshold, Sensitivity);
                            break;
                        end
        
                        for i = 1:size(X_train,1)
                            target_class = y_train(i) + 1;
                            tm = tm.update(X_train(i,:), target_class); %#ok<NASGU>
                        end
        
                        accTrainSeries(ep) = tm.evaluate(X_train, y_train); %#ok<NASGU>
        
                        % อัปเดตเส้นเดิมแทนการ plot ใหม่
                        if mod(ep, showStep) == 0 || ep == 1 || ep == Epochs
                            x = 1:showStep:ep; if x(end) ~= ep, x = [x, ep]; end
                            set(app.hTrain,'XData',x,'YData',accTrainSeries(x));
                            drawnow limitrate;
                        end
                    end
                    bestValAcc = tm.evaluate(X_val, y_val); %#ok<NASGU>
                end
        
                if ~wasStopped
                    epStopped = Epochs;
                end
        
                % === สรุปผล (โมเดลสุดท้าย) ===
                final_train_acc = tm.evaluate(X_train, y_train) * 100;
                final_val_acc   = tm.evaluate(X_val,   y_val)   * 100;
        
                if ~isempty(app.XTest) && ~isempty(app.yTest)
                    acc_test = tm.evaluate(app.XTest, app.yTest) * 100;
                    testStr  = sprintf('Final Test Accuracy: %.2f%%', acc_test);
                else
                    acc_test = NaN;
                    testStr  = 'Final Test Accuracy: ไม่ได้เลือกไฟล์สำหรับ Test';
                end
        
                % === คืน UI แล้วค่อยเปลี่ยนไฟเขียวถ้าจบครบ ===
                restoreUI();
                if ~wasStopped
                    app.TrainLamp.Color = [0 1 0];   % เขียว (จบครบ)
                end
        
                % (เก็บค่าไว้เฉย ๆ)
                app.ThresholdState.Text   = sprintf('T = %.2f', Threshold);
                app.SensitivityState.Text = sprintf('S = %.2f', Sensitivity);
        
                % === ทำ popup ผลลัพธ์ ===
                tmpFigFile = [tempname, '.png'];
                exportgraphics(app.UIAxes, tmpFigFile, 'Resolution', 220);
        
                epochLine = '';
                if ~wasStopped, epochLine = sprintf('Epochs: %d\n', epStopped); end
        
                if strcmp(app.Switch2.Value, "Sweep Mode")
                    paramStr = sprintf([ ...
                        'Sweep Mode :\n' ...
                        'Threshold Range: %.4g to %.4g (Step: %.4g)\n' ...
                        'Sensitivity Range: %.4g to %.4g (Step: %.4g)\n' ...
                        'Best Threshold: %.4g\nBest Sensitivity: %.4g\n' ...
                        'Clauses: %d\nStates: %d\nFeatures: %d\nClasses: %d\n' ...
                        '%s' ...
                        '\nFinal Train Accuracy: %.2f%%\n%s\n%s' ], ...
                        ThresholdStart,ThresholdEnd,ThresholdStep, ...
                        SensitivityStart,SensitivityEnd,SensitivityStep, ...
                        Threshold,Sensitivity, ...
                        NumClauses,NumStates,NumFeatures,NumClasses, ...
                        epochLine, ...
                        final_train_acc, testStr, stopNotice);
                else
                    paramStr = sprintf([ ...
                        'Fix Mode :\n' ...
                        'Threshold: %s\nSensitivity: %s\n' ...
                        'Number of Clauses: %s\nNumber of States: %s\n' ...
                        'Number of Features: %s\nNumber of Classes: %s\n' ...
                        '%s' ...
                        '\nFinal Train Accuracy: %.2f%%\nFinal Test Accuracy: %s\n%s' ], ...
                        app.Threshold_1.Value,app.Sensitivity_1.Value, ...
                        app.NumberofClauses_1.Value,app.NumberofStates_1.Value, ...
                        app.NumberofFeatures_1.Value,app.NumberofClasses_1.Value, ...
                        epochLine, ...
                        final_train_acc, ...
                        iif(~isnan(acc_test), sprintf('%.2f%%', acc_test), 'ไม่ได้เลือกไฟล์สำหรับ Test'), ...
                        stopNotice);
                end
        
                figW = 757; figH = 612;
                M = 28; gap1 = 5; gap2 = 24; btnGap = 24;
                axH = 280; axW = figW - 2*M; axX = M; axY = figH - M - axH;
                msgW = 320; msgH = 200;
                cx = @(w) (figW - w)/2;
                msgX = cx(msgW); msgY = axY - gap1 - msgH;
                btnW = 140; btnH = 34; btnY = msgY - gap2 - btnH;
                leftX  = cx(2*btnW + btnGap);
                rightX = leftX + btnW + btnGap;
        
                popupFig = uifigure('Name','Training Result', 'Position',[100 100 figW figH]);
                ax = uiaxes(popupFig, 'Position', [axX axY axW axH]);
                imshow(tmpFigFile, 'Parent', ax); ax.Visible = 'off';
        
                uitextarea(popupFig, 'Value', splitlines(paramStr), ...
                    'Position', [msgX msgY msgW msgH], 'Editable', 'off');
        
                uibutton(popupFig,'push','Text','Save Graph', ...
                    'Position',[leftX btnY btnW btnH], 'ButtonPushedFcn', @(~,~) saveGraph());
                uibutton(popupFig,'push','Text','Save Parameter', ...
                    'Position',[rightX btnY btnW btnH], 'ButtonPushedFcn', @(~,~) saveParams());
        
                app.TrainedModel = tm;
        
            catch ME
                % เกิด error ใด ๆ ให้คืน UI เสมอ
                restoreUI();
                uialert(app.UIFigure, "Error while training: " + ME.message, 'Error','Icon','error');
                return;
            end
        
            % ===== Nested helpers =====
            function restoreUI()
                showTS(app, false);
                app.IsTraining          = false;
                app.TrainLabel.Text     = 'Train';
                app.TrainLamp.Color     = [0.5 0.5 0.5];
                app.StopButton.Enable   = 'off';
                app.TrainButton.Enable  = 'on';
        
                app.Switch2.Enable              = 'on';
                app.SelectedFileLabel.Enable    = 'on';
                app.SelectTestFileButton.Enable = 'on';
                app.ResetButton.Enable          = 'on';
        
                setControlsEnabled(app, true);
                drawnow;
            end
        
            function stopped = trainOnePair(T,S)
                stopped = false;
                app.ThresholdState.Text   = sprintf('T = %.2f', T);
                app.SensitivityState.Text = sprintf('S = %.2f', S);
                drawnow limitrate;
        
                % กราฟของคู่ปัจจุบัน (เส้นเดียว)
                cla(app.UIAxes);
                hold(app.UIAxes, 'on');
                xlabel(app.UIAxes,'Epoch'); ylabel(app.UIAxes,'Accuracy');
                title(app.UIAxes,'Training Accuracy'); grid(app.UIAxes,'on');
                xlim(app.UIAxes,[1 Epochs]); ylim(app.UIAxes,[0 1]);
                app.hTrain = plot(app.UIAxes, NaN, NaN, '-');
        
                tm_tmp = TsetlinMachine(NumClasses, NumClauses, NumFeatures, NumStates, S, T, 1);
        
                for ep = 1:Epochs
                    if app.stopFlag
                        wasStopped = true;
                        epStopped  = ep;
                        stopNotice = sprintf('⚠ Training stopped at Epoch %d (Threshold = %.2f, Sensitivity = %.2f)\n', epStopped, T, S);
                        stopped = true;
                        break;
                    end
                    for i = 1:size(X_train,1)
                        target_class = y_train(i) + 1;
                        tm_tmp = tm_tmp.update(X_train(i,:), target_class);
                    end
                    accTrainSeries(ep) = tm_tmp.evaluate(X_train, y_train);
        
                    if mod(ep, showStep) == 0 || ep == 1 || ep == Epochs
                        x = 1:showStep:ep; if x(end) ~= ep, x = [x, ep]; end
                        set(app.hTrain,'XData',x,'YData',accTrainSeries(x));  % ← อัปเดตเส้นเดิม
                        drawnow limitrate;
                    end
                end
                if ~stopped
                    thisVal = tm_tmp.evaluate(X_val, y_val);
                    if thisVal > bestValAcc
                        bestValAcc = thisVal; bestModel = tm_tmp; bestT = T; bestS = S;
                    end
                end
            end
        
            function saveGraph()
                [file, path] = uiputfile('graph.png','Save Graph As');
                if isequal(file,0), return; end
                exportgraphics(app.UIAxes, fullfile(path, file));
                uialert(app.UIFigure,'Graph saved successfully!','Done','Icon','success');
            end
        
            function saveParams()
                if strcmp(app.Switch2.Value,"Sweep Mode")
                    rows = {
                        'Mode','Sweep Mode'
                        'Threshold Range Start', num2str(ThresholdStart)
                        'Threshold Range End',   num2str(ThresholdEnd)
                        'Threshold Step',        num2str(ThresholdStep)
                        'Sensitivity Range Start', num2str(SensitivityStart)
                        'Sensitivity Range End',   num2str(SensitivityEnd)
                        'Sensitivity Step',        num2str(SensitivityStep)
                        'Best Threshold (used)',   num2str(Threshold)
                        'Best Sensitivity (used)', num2str(Sensitivity)
                        'Best Validation Accuracy (%)', num2str(bestValAcc*100,'%.4f')
                        'Number of Clauses', num2str(NumClauses)
                        'Number of States',  num2str(NumStates)
                        'Number of Features',num2str(NumFeatures)
                        'Number of Classes', num2str(NumClasses)
                    };
                else
                    rows = {
                        'Mode','Fix Mode'
                        'Threshold (used)', num2str(Threshold)
                        'Sensitivity (used)', num2str(Sensitivity)
                        'Number of Clauses', num2str(NumClauses)
                        'Number of States',  num2str(NumStates)
                        'Number of Features',num2str(NumFeatures)
                        'Number of Classes', num2str(NumClasses)
                    };
                end
                if ~wasStopped
                    rows = [rows; {'Epochs', num2str(epStopped)}]; %#ok<AGROW>
                end
                rows = [rows; {
                    'Final Train Accuracy (%)', num2str(final_train_acc,'%.4f')
                    'Final Val Accuracy (%)',   num2str(final_val_acc,'%.4f')
                    'Final Test Accuracy (%)',  iif(~isnan(acc_test),num2str(acc_test,'%.4f'),'N/A')
                    'Training File', safeText(app.TrainingfileLabel.Text)
                    'Test File',     safeText(app.TestfileLabel.Text)
                    'Timestamp',     char(datetime('now','Format','yyyy-MM-dd HH:mm:ss'))
                }];
                T = cell2table(rows,'VariableNames',{'Parameter','Value'});
                [file,path] = uiputfile('parameters.xlsx','Save Parameters As');
                if isequal(file,0), return; end
                writetable(T, fullfile(path,file));
                uialert(app.UIFigure,'Parameter saved to Excel successfully!','Done','Icon','success');
            end
        
            function val = getParam(leftVal,rightVal)
                if ~isempty(strtrim(rightVal)), val = rightVal; else, val = leftVal; end
            end
            function out = iif(cond,a,b)
                if cond, out=a; else, out=b; end
            end
            function s = safeText(x)
                if isempty(x), s=''; else, s=char(string(x)); end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            showTS(app, false);   % << ซ่อนเมื่อหยุด
            app.stopFlag = true;
            app.StopButton.Enable = 'off';
        
            % เปลี่ยนข้อความ + สีไฟเป็นส้ม
            app.TrainLabel.Text = 'Train';
            app.TrainLamp.Color = [1.0, 0.6, 0.0];   % ส้ม
            drawnow;
        
            % ตั้ง Timer → 3 วิ แล้วเปลี่ยนเป็นเทา
            if ~isempty(app.LampTimer) && isvalid(app.LampTimer)
                stop(app.LampTimer); delete(app.LampTimer);
            end
            app.LampTimer = timer( ...
                'StartDelay', 3, ...
                'ExecutionMode', 'singleShot', ...
                'TimerFcn', @(~,~) setLampGray(app));
            start(app.LampTimer);
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            % Reset ค่าอินพุตทั้งหมดและจัดสถานะตามโหมด
            resetInputsKeepFiles(app);

            if strcmp(app.Switch2.Value, 'Fix Mode')
                app.Threshold_2.Enable    = 'off';
                app.Sensitivity_2.Enable  = 'off';
                app.ThresholdStep.Enable  = 'off';
                app.SensitivityStep.Enable= 'off';
                app.ToLabel.Enable        = 'off';
                app.StepLabel.Enable      = 'off';
                app.ToLabel_2.Enable      = 'off';
                app.StepLabel_2.Enable    = 'off';
                app.SweepOrder.Enable     = 'off';
                app.SweepOrderLabel.Enable= 'off';
            else
                app.Threshold_2.Enable    = 'on';
                app.Sensitivity_2.Enable  = 'on';
                app.ThresholdStep.Enable  = 'on';
                app.SensitivityStep.Enable= 'on';
                app.ToLabel.Enable        = 'on';
                app.StepLabel.Enable      = 'on';
                app.ToLabel_2.Enable      = 'on';
                app.StepLabel_2.Enable    = 'on';
                app.SweepOrder.Enable     = 'on';
                app.SweepOrderLabel.Enable= 'on';
            end
        end
        
        % === ฟังก์ชัน Reset ช่องทั้งหมด ===
        function resetAllInputs(app)
            % ฝั่งซ้าย
            app.Threshold_1.Value        = '';
            app.Sensitivity_1.Value      = '';
            app.NumberofClauses_1.Value  = '';
            app.NumberofStates_1.Value   = '';
            app.NumberofFeatures_1.Value = '';
            app.NumberofClasses_1.Value  = '';
            app.Epochs_1.Value           = '';
        
            % ฝั่งขวา (ช่วง)
            app.Threshold_2.Value        = '';
            app.Sensitivity_2.Value      = '';
        
            % Step
            app.ThresholdStep.Value      = '';
            app.SensitivityStep.Value    = '';

            app.TrainingfileLabel.Text               = '';
            app.TestfileLabel.Text             = '';
        
            % ล้างกราฟ
            cla(app.UIAxes);
            title(app.UIAxes,''); xlabel(app.UIAxes,'Epoch'); ylabel(app.UIAxes,'Accuracy'); grid(app.UIAxes,'on');
        
            % ปิด Lamp
            app.TrainLamp.Color = [0.5 0.5 0.5];
        
            % ล้างโมเดล
            app.TrainedModel = [];
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: SelectTestFileButton
        function SelectTestFileButtonPushed(app, event)
            [file, path] = uigetfile({ ...
                '*.csv',  'CSV Files (*.csv)'; ...
                '*.xlsx', 'Excel Files (*.xlsx)'}, ...
                'Select Test File');
        
            if isequal(file,0)
                if isempty(app.XTest) || isempty(app.yTest)
                    app.TestfileLabel.Text = 'ยังไม่ได้เลือก Test file';
                end
                return;
            end
        
            fullPath = fullfile(path,file);
            try
                data = readmatrix(fullPath);
            catch ME
                uialert(app.UIFigure,"อ่านไฟล์ทดสอบไม่สำเร็จ: " + ME.message,'Error','Icon','error');
                return;
            end
            if size(data,2) < 2
                uialert(app.UIFigure,'ไฟล์ต้องมีอย่างน้อย 2 คอลัมน์ (คอลัมน์แรกเป็น label)','รูปแบบไฟล์ไม่ถูกต้อง','Icon','error');
                return;
            end
        
            app.TestfileLabel.Text = file;   % แสดงชื่อไฟล์ Test
            app.yTest = data(:,1);
            app.XTest = data(:,2:end);
        
            disp("Test file loaded successfully: " + file);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Epochs')
            ylabel(app.UIAxes, 'Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.Threshold_2 = uieditfield(app.UIFigure, 'text');
            app.Sensitivity_2 = uieditfield(app.UIFigure, 'text');
            app.ThresholdTEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdTEditFieldLabel.Text = 'Threshold (T):';
            app.Threshold_1 = uieditfield(app.UIFigure, 'text');
            app.SensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity :';
            app.Sensitivity_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClausesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClausesEditFieldLabel.Text = 'Number of Clauses :';
            app.NumberofClauses_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofStatesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofStatesEditFieldLabel.Text = 'Number of States :';
            app.NumberofStates_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofFeaturesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFeaturesEditFieldLabel.Text = 'Number of Features :';
            app.NumberofFeatures_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClassesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClassesEditFieldLabel.Text = 'Number of Classes :';
            app.NumberofClasses_1 = uieditfield(app.UIFigure, 'text');
            app.EpochsEditFieldLabel = uilabel(app.UIFigure);
            app.EpochsEditFieldLabel.Text = 'Epochs :';
            app.Epochs_1 = uieditfield(app.UIFigure, 'text');
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.Items = {'Sweep Mode', 'Fix Mode'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Value = 'Sweep Mode';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TrainLamp = uilamp(app.UIFigure);
            app.SelectedFileLabel = uibutton(app.UIFigure, 'push');
            app.SelectedFileLabel.ButtonPushedFcn = createCallbackFcn(app, @SelectedFileLabelPushed, true);
            app.SelectedFileLabel.Text = 'Select Training File';
            app.SelectTestFileButton = uibutton(app.UIFigure, 'push');
            app.SelectTestFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTestFileButtonPushed, true);
            app.SelectTestFileButton.Text = 'Select Test File';
            app.TrainingfileLabel = uilabel(app.UIFigure);
            app.TrainingfileLabel.Text = 'ยังไม่ได้เลือก Training file';
            app.ThresholdStep = uieditfield(app.UIFigure, 'text');
            app.SensitivityStep = uieditfield(app.UIFigure, 'text');
            app.TestfileLabel = uilabel(app.UIFigure);
            app.TestfileLabel.Text = 'ยังไม่ได้เลือก Test file';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To';
            app.StepLabel = uilabel(app.UIFigure);
            app.StepLabel.Text = 'Step :';
            app.ToLabel_2 = uilabel(app.UIFigure);
            app.ToLabel_2.Text = 'To';
            app.StepLabel_2 = uilabel(app.UIFigure);
            app.StepLabel_2.Text = 'Step :';
            app.TrainLabel = uilabel(app.UIFigure);
            app.TrainLabel.Text = 'Train';
            app.ThresholdState = uilabel(app.UIFigure);
            app.ThresholdState.Text = '';
            app.SensitivityState = uilabel(app.UIFigure);
            app.SensitivityState.Text = '';
            app.ShowEpochsLabel = uilabel(app.UIFigure);
            app.ShowEpochsLabel.Text = 'Plot (epochs):';
            app.ShowEpochs = uieditfield(app.UIFigure, 'text');
            app.SweepOrderLabel = uilabel(app.UIFigure);
            app.SweepOrderLabel.Text = 'SweepOrder';
            app.SweepOrder = uidropdown(app.UIFigure);
            app.SweepOrder.Items = {'T→S', 'S→T'};
            app.SweepOrder.Value = 'T→S';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TsetlinGUI_With_Call_Model_2_Selectfile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end